classdef SANIdentifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        REGISTRATIONTab                matlab.ui.container.Tab
        IMPORTButton                   matlab.ui.control.Button
        YZButton                       matlab.ui.control.Button
        XZButton                       matlab.ui.control.Button
        XYButton                       matlab.ui.control.Button
        HIDEButton                     matlab.ui.control.Button
        Label_4                        matlab.ui.control.Label
        FrameSpinner                   matlab.ui.control.Spinner
        Button                         matlab.ui.control.Button
        VolumeRenderingPanel           matlab.ui.container.Panel
        Label_3                        matlab.ui.control.Label
        RMSETextArea                   matlab.ui.control.TextArea
        ScalingPanel                   matlab.ui.container.Panel
        syncaxesCheckBox               matlab.ui.control.CheckBox
        ZSpinner_Sca                   matlab.ui.control.Spinner
        ZLabel                         matlab.ui.control.Label
        YSpinner_Sca                   matlab.ui.control.Spinner
        YLabel                         matlab.ui.control.Label
        XSpinner_Sca                   matlab.ui.control.Spinner
        XSpinnerLabel_2                matlab.ui.control.Label
        ReflectionPanel                matlab.ui.container.Panel
        zaxisSwitch                    matlab.ui.control.Switch
        zaxisSwitchLabel               matlab.ui.control.Label
        yaxisSwitch                    matlab.ui.control.Switch
        yaxisSwitchLabel               matlab.ui.control.Label
        xaxisSwitch                    matlab.ui.control.Switch
        xaxisSwitchLabel               matlab.ui.control.Label
        RotationPanel                  matlab.ui.container.Panel
        ZSpinner_Rot                   matlab.ui.control.Spinner
        ZSpinner_2Label                matlab.ui.control.Label
        YSpinner_Rot                   matlab.ui.control.Spinner
        YSpinner_2Label                matlab.ui.control.Label
        XSpinner_Rot                   matlab.ui.control.Spinner
        XSpinner_2Label                matlab.ui.control.Label
        TranslationPanel               matlab.ui.container.Panel
        ZSpinner_TX                    matlab.ui.control.Spinner
        ZSpinnerLabel                  matlab.ui.control.Label
        YSpinner_TX                    matlab.ui.control.Spinner
        YSpinnerLabel                  matlab.ui.control.Label
        XSpinner_TX                    matlab.ui.control.Spinner
        XSpinnerLabel                  matlab.ui.control.Label
        AUTOREGISTERButton             matlab.ui.control.Button
        RESETButton                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_MP                      matlab.ui.control.UIAxes
        ANALYSISTab                    matlab.ui.container.Tab
        TracklengthSpinner             matlab.ui.control.Spinner
        TracklengthLabel               matlab.ui.control.Label
        SNAPButton                     matlab.ui.control.Button
        SelectedNeuronsLabel           matlab.ui.control.Label
        FilteredNeuronsLabel           matlab.ui.control.Label
        IdentificationalgorithmSwitch  matlab.ui.control.Switch
        IdentificationalgorithmSwitchLabel  matlab.ui.control.Label
        PLAYButton                     matlab.ui.control.Button
        ListBox_Selected               matlab.ui.control.ListBox
        Label_6                        matlab.ui.control.Label
        NeuronsLabel                   matlab.ui.control.Label
        EXPORTButton                   matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        ListBox_Filtered               matlab.ui.control.ListBox
        IntensitymaxSpinner            matlab.ui.control.Spinner
        IntensitymaxSpinnerLabel       matlab.ui.control.Label
        AmplitudestdSpinner            matlab.ui.control.Spinner
        AmplitudestdSpinnerLabel       matlab.ui.control.Label
        LOADNEURONSButton              matlab.ui.control.Button
        UIAxes_Location                matlab.ui.control.UIAxes
        UIAxes_MI                      matlab.ui.control.UIAxes
        UIAxes_GCaMP                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        RefCoordHead=[94.3400000000000	10.3100000000000	0.0300000000000000
            91.2200000000000	-11.6400000000000	-1.43000000000000
            87.9700000000000	10.5000000000000	-0.120000000000000
            83.2300000000000	-11.4700000000000	-0.580000000000000
            54.1800000000000	7	8.48000000000000
            54.6300000000000	-8.88000000000000	7.15000000000000
            59.1300000000000	3.86000000000000	12.0500000000000
            58.8000000000000	-6.34000000000000	11.3800000000000
            53.3700000000000	7.49000000000000	5.99000000000000
            53.1100000000000	-9.19000000000000	5.71000000000000
            66.7400000000000	0.660000000000000	-4.67000000000000
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            113.770000000000	1.06000000000000	-8.15000000000000
            132.470000000000	-1.74000000000000	-9.95000000000000]; % Head atlas coordinates

        IDN=["ADAL"
            "ADAR"
            "ADEL"
            "ADER"
            "ADFL"
            "ADFR"
            "ADLL"
            "ADLR"
            "AFDL"
            "AFDR"
            "AIAL"
            "AIAR"
            "AIBL"
            "AIBR"
            "AIML"
            "AIMR"
            "AINL"
            "AINR"
            "AIYL"
            "AIYR"
            "AIZL"
            "AIZR"
            "ALA"
            "AQR"
            "AS1"
            "ASEL"
            "ASER"
            "ASGL"
            "ASGR"
            "ASHL"
            "ASHR"
            "ASIL"
            "ASIR"
            "ASJL"
            "ASJR"
            "ASKL"
            "ASKR"
            "AUAL"
            "AUAR"
            "AVAL"
            "AVAR"
            "AVBL"
            "AVBR"
            "AVDL"
            "AVDR"
            "AVEL"
            "AVER"
            "AVFL"
            "AVFR"
            "AVG"
            "AVHL"
            "AVHR"
            "AVJL"
            "AVJR"
            "AVKL"
            "AVKR"
            "AVL"
            "AWAL"
            "AWAR"
            "AWBL"
            "AWBR"
            "AWCL"
            "AWCR"
            "BAGL"
            "BAGR"
            "CEPDL"
            "CEPDR"
            "CEPVL"
            "CEPVR"
            "DA1"
            "DB1"
            "DB2"
            "DD1"
            "FLPL"
            "FLPR"
            "I1L"
            "I1R"
            "I2L"
            "I2R"
            "I3"
            "I4"
            "I5"
            "I6"
            "IL1DL"
            "IL1DR"
            "IL1L"
            "IL1R"
            "IL1VL"
            "IL1VR"
            "IL2DL"
            "IL2DR"
            "IL2L"
            "IL2R"
            "IL2VL"
            "IL2VR"
            "M1"
            "M2L"
            "M2R"
            "M3L"
            "M3R"
            "M4"
            "M5"
            "MCL"
            "MCR"
            "MI"
            "NSML"
            "NSMR"
            "OLLL"
            "OLLR"
            "OLQDL"
            "OLQDR"
            "OLQVL"
            "OLQVR"
            "RIAL"
            "RIAR"
            "RIBL"
            "RIBR"
            "RICL"
            "RICR"
            "RID"
            "RIFL"
            "RIFR"
            "RIGL"
            "RIGR"
            "RIH"
            "RIML"
            "RIMR"
            "RIPL"
            "RIPR"
            "RIR"
            "RIS"
            "RIVL"
            "RIVR"
            "RMDDL"
            "RMDDR"
            "RMDL"
            "RMDR"
            "RMDVL"
            "RMDVR"
            "RMED"
            "RMEL"
            "RMER"
            "RMEV"
            "RMFL"
            "RMFR"
            "RMGL"
            "RMGR"
            "RMHL"
            "RMHR"
            "SAADL"
            "SAADR"
            "SAAVL"
            "SAAVR"
            "SABD"
            "SABVL"
            "SABVR"
            "SIADL"
            "SIADR"
            "SIAVL"
            "SIAVR"
            "SIBDL"
            "SIBDR"
            "SIBVL"
            "SIBVR"
            "SMBDL"
            "SMBDR"
            "SMBVL"
            "SMBVR"
            "SMDDL"
            "SMDDR"
            "SMDVL"
            "SMDVR"
            "URADL"
            "URADR"
            "URAVL"
            "URAVR"
            "URBL"
            "URBR"
            "URXL"
            "URXR"
            "URYDL"
            "URYDR"
            "URYVL"
            "URYVR"
            "VA1"
            "VB1"
            "VB2"
            "VD1"
            "VD2"]; % Neurons IDs

        % RefCoordDopa=[87.9700000000000	10.5000000000000	-0.120000000000000
        %     83.2300000000000	-11.4700000000000	-0.580000000000000
        %     49.5800000000000	3.70000000000000	13.6200000000000
        %     50.2800000000000	-7.07000000000000	12.9000000000000
        %     39.3400000000000	4.58000000000000	1.03000000000000
        %     40.6800000000000	-4.42000000000000	1.01000000000000]; % Coord dopaminergic neurons
        % IDDOPA=["ADEL"
        %     "ADER"
        %     "CEPDL"
        %     "CEPDR"
        %     "CEPVL"
        %     "CEPVR"]; % ID dopaminergic neurons
        %
        Refdopid=[3;4;66;67;68;69]; % indices of dopaminergic neurons in ref atlas
        nRefdopid % indices of non-dpomainergic neurons in ref atlas
        dopid %indices of dopaminergic neurons from experimental set
        ndopid %indices of non dopaminergic neurons in experimental set
        RefVectorCellArray % 189-by-1 cell array containing the xyz differential between each ref neurons
        % (188-by-3 matrix -self distanced neurons
        % deleted)
        ExpVectorCellArray % nExpNeurons-by-1 cell array containing xyz differental between each tracked
        % neurons ([nExpNeurons-1]-by-3 matrix)
        AssignNeurons % Neurons detected by assignment algo
        scale %[xy z] pixel physical sizes
        pd %dir structure of Labels mat files from SD
        pdg %dir structure of gCaMP channel stacks (green)
        pdb %dir sutructure of dopaminergic channel (blue)
        Gstack % gCaMP max Proj stack
        gCaMPData % data structure from gCaMP channel regionprops
        CentOri % cell array containing original centroids, one cell per tracked neuron
        FrameLabels % cell array containing Label objects
        cellStack % cell array containing raw image data (one cell per timeframe)
        prepData % cell array containing registered coordinates
        rmse %rmse value for scoring registration
        lvol %Volume redenring object
        counter = 1; %frame counter
        qim %0.99 quantile of gCaMP intensity
        qimi %0.01 quantile of gCaMP intensity
        himage % handle of gCaMP MP
        view3d %viewer3d object
        nF% number of  time frames total
        nN % number of tracked neurons
        nFramesNeurons % track length for each neurons
        ftext % frame string
        pcRef %head reaf point cloud
        pcMoving %point cloud from frame with highest cell number
        pcReg % point cloud of first icd registered pcMoving
        pcRegReset % reset pointCloud
        refcl % location of pcRef
        tform % transform matrix
        rsme1 %very first rsme value
        Imstate = false % state of Imshow
        pcState = true % state of showing/hiding non dopa pc
        % affine transformation object
        href  % handle  of scatter3 ref points
        hrefloc % handle  of scatter3 ref points analysis pane
        hreflocs % handle of scatter3 from selected neurons closest ref
        hreg % handle of scatter3 experimental points
        hregloc % handle of scatter3 experimental points analysis pane
        hreglocs % handle of scatter3 from selected experimental neuron
        neuronS %final neuron structure
        neuroP % selected neurons from filtered list

        %% Tab Analysis %%

        mami % max mean intensity all neurons
        mimi % min mean intensity all  neurons
        avmi % average mean intensity  all neurons
        sneuronMI % cell array of  single neurons MI
        sneuronNorm % cell array of single neurons normalized intensity
        smami % max MI single neurons
        smanorm % max norm int neurons
        NP % cell array of neuron properties
        NID % cell array of neuronID
        NPS % concatenated Neuron Properties structure
        himca % handle for gCaMP tracking
        elist % list of selected neurons in Filtered listbox
        nfind % indices of filtered neurons
        nselect % indices of selected neurons
        tLength = 1; % minimum track length selected
        neurovalue % currently selected neuron

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IMPORTButton
        function IMPORTButtonPushed(app, event)

            %%%% app initialization and ref vector calculations %%%%%%%%%%
            app.counter=1;
            cla(app.UIAxes_MP);
            cla(app.UIAxes);
            delete(app.lvol);
            app.Label_3.Visible='on';
            app.Label_3.Text='';
            app.Label_4.Visible='on';
            app.Label_4.Text='';
            app.Imstate=false;
            app.xaxisSwitch.Value='Off';
            app.yaxisSwitch.Value='Off';
            app.zaxisSwitch.Value='Off';
            app.FrameSpinner.Value=1;

            app.ListBox_Filtered.Items={''};
            app.ListBox_Filtered.ItemsData=[];
            app.ListBox_Selected.Items={''};
            app.ListBox_Selected.ItemsData=[];
            cla(app.UIAxes_MI);
            cla(app.UIAxes_GCaMP);
            app.Label_6.Text='';
            app.UIAxes_MP.Title.Color= [1 1 1];
            app.UIAxes.Title.Color= [1 1 1];
            app.UIAxes.XLabel.Color=[1 1 1];
            app.UIAxes.YLabel.Color=[1 1 1];
            app.UIAxes.ZLabel.Color=[1 1 1];
            app.UIAxes.XColor=[1 1 1];
            app.UIAxes.YColor=[1 1 1];
            app.UIAxes.ZColor=[1 1 1];
            app.UIAxes_GCaMP.Title.Color=[1 1 1];

            axis(app.UIAxes_GCaMP,'off');
            app.UIAxes_MI.Title.Color=[1 1 1];
            app.UIAxes_MI.XLabel.Color=[1 1 1];
            app.UIAxes_MI.YLabel.Color=[1 1 1];

            app.UIAxes_MI.XColor=[1 1 1];
            app.UIAxes_MI.YColor=[1 1 1];
            app.UIAxes_Location.Title.Color=[1 1 1];
            app.UIAxes_Location.XLabel.Color=[1 1 1];
            app.UIAxes_Location.YLabel.Color=[1 1 1];
            app.UIAxes_Location.ZLabel.Color=[1 1 1];
            app.UIAxes_Location.XColor=[1 1 1];
            app.UIAxes_Location.YColor=[1 1 1];
            app.UIAxes_Location.ZColor=[1 1 1];

            children = get(app.REGISTRATIONTab,'Children');
            set(children(isprop(children,'Enable')),'Enable','on');


            RefVectorMatrix=zeros(189,3,189);

            for i=1:189
                k=app.RefCoordHead(i,:);
                RefVectorMatrix(:,:,i)=k-app.RefCoordHead;
            end

            REFMC=mat2cell(RefVectorMatrix,189,3,ones(189,1));
            app.RefVectorCellArray=cellfun(@(x) x(all(x,2),:),...
                REFMC,'UniformOutput',false);
            app.RefVectorCellArray=squeeze(app.RefVectorCellArray);


            %%% Load Data %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.UIFigure.Visible="off";
            commandwindow
            selpath=uigetdir;
            if ischar(selpath)
                cd(selpath);
            else
                return
            end
            app.pd=dir('L*.mat');
            app.pdg=dir('G*.tif');
            app.pdb=dir('B*.tif');
            app.nF=numel(app.pd);
            % if app.nF>1
            %     app.FrameSpinner.Limits=[1 app.nF];
            % else
            %     app.FrameSpinner.Enable='off';
            % end
            I=imread(app.pdg(1).name,1);
            sizeI=size(I);
            app.Gstack=zeros(sizeI(1),sizeI(2),app.nF,'like',I);
            app.cellStack=cell(app.nF,1);

            %%% Manually input voxel physical size

            [vx, vz]=xzprompt();
            app.scale=[vx vz];

            %%% Only consider case of 1 timeframe with 3 channels %%%%%%%%%

            if (numel(app.pd) ==1) && (numel(app.pdg)==1)...
                    && (numel(app.pdb)==1)

                IG=bfopen(app.pdg(1).name);
                II=cat(3,IG{1,1}{:,1});
                app.cellStack=cell(5,1);
                Ip=max(II,[],3);
                app.Gstack=repmat(Ip,1,1,5);

                ISB=bfopen(app.pdb(1).name);
                IB=cat(3,ISB{1,1}{:,1});
                S=load(app.pd(1).name); % 'Lcomb' should be the only variable
                fn=fieldnames(S);
                L=S.(fn{1});
                [app.dopid, app.ndopid]=SANgetDopCentroid(L,IB);
                app.nRefdopid=1:189;
                app.nRefdopid(app.Refdopid)=[];


                for i=1:5 %% replicate frame for pseudo timelapse
                    app.cellStack{i}=II;

                end

                %%% metadata appear not to be transferred when saving single channels separately  %%%

                % omeMeta=IS{1,4};
                % voxX = omeMeta.getPixelsPhysicalSizeX(0);
                % voxZ = omeMeta.getPixelsPhysicalSizeZ(0);
                % app.scale(1)=double(voxX.value());
                % app.scale(2)=double(voxZ.value());

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                app.UIFigure.Visible='on';
                app.qim=quantile(app.Gstack(:),0.99);
                app.qimi=quantile(app.Gstack(:),0.01);
                app.himage=imshow(app.Gstack(:,:,1),[app.qimi app.qim],...
                    'Parent',app.UIAxes_MP);
                app.ftext=text(app.UIAxes_MP,50,50,'1','Color','y');
                drawnow;
                app.Label_3.Text='Computing Volume...';drawnow;
                app.Label_4.Text='Computing Point Cloud...';drawnow;

                [app.FrameLabels, app.prepData, app.rmse, ncflag] = ...
                    SANprepData(L);
                if ncflag
                    uialert(app.UIFigure,...
                        ['Not enough neurons detected (only ' int2str(ncflag) ')'],...
                        'bad quality dataset');

                    children = get(app.REGISTRATIONTab,'Children');
                    set(children(isprop(children,'Enable')),'Enable','off');
                    app.Button.Enable='on';
                    app.IMPORTButton.Enable='on';
                    return
                end

                app.gCaMPData=SANPostStarDistAPP(app.cellStack,...
                    app.FrameLabels,app.prepData);
                cen=cellfun(@(x) x.OrigCentroid,app.gCaMPData,...
                    'UniformOutput',false);
                app.nFramesNeurons=cellfun(@(x) size(x,1),cen);
                mcen=cellfun(@(x) mean(x,1,'omitnan'),cen,'UniformOutput',false);
                app.nN=size(mcen,1);
                vmcen=vertcat(mcen{:});
                vmcen(:,[1 2])=vmcen(:,[1 2])*app.scale(1);
                vmcen(:,3)=vmcen(:,3)*app.scale(2);
                app.pcMoving=pointCloud(vmcen);
                app.Label_3.Visible='off';

                app.lvol=volshow(app.FrameLabels{1}>0,...
                    'Parent',app.VolumeRenderingPanel);
                app.lvol.Transformation.A(3,3)=app.scale(2)/app.scale(1);
                app.view3d=app.lvol.Parent;
                % app.ZoomSlider.Value=app.view3d.CameraZoom;

                %%% creating point clouds and initializing registration

                app.pcRef=pointCloud(app.RefCoordHead);
                [~, app.pcReg,~]=pcregistercpd(app.pcMoving,app.pcRef);
                app.pcRegReset=app.pcReg;
                app.Label_4.Visible='off';
                app.refcl=app.pcRef.Location;
                regcl=app.pcReg.Location;
                app.href=scatter3(app.refcl(:,1),app.refcl(:,2),app.refcl(:,3),...
                    72,'g','filled','Parent',app.UIAxes);
                app.href.MarkerFaceAlpha='flat';
                app.href.AlphaDataMapping='none';
                app.href.AlphaData=0.2*ones(189,1);
                app.href.AlphaData(app.Refdopid)=1;

                app.UIAxes.XLabel.String='X  (AP)';
                app.UIAxes.YLabel.String='Y (LR)';
                app.UIAxes.ZLabel.String='Z  (DV)';

                axis(app.UIAxes,'equal');hold(app.UIAxes,'on');
                app.hreg=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),72,...
                    'm','filled','Parent',app.UIAxes);
                app.hreg.MarkerFaceAlpha='flat';
                app.hreg.AlphaDataMapping='none';
                app.hreg.AlphaData=0.2*ones(numel(app.hreg.XData),1);
                app.hreg.AlphaData(app.dopid)=1;

                app.UIAxes.Color=[0.2 0.2 0.2];
                app.UIAxes.GridColor=[1 1 1];
                grid(app.UIAxes,'on')


                [~,dist]=knnsearch(app.refcl,regcl);
                app.rsme1=mean(dist);
                app.RMSETextArea.Value=num2str(app.rsme1,4);

            else

                warning;
            end


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if  app.Imstate
                app.Imstate=false;
                app.Button.Text='>';
            else
                app.Imstate=true;
                app.Button.Text='||';
            end
            while app.Imstate
                app.himage.CData=app.Gstack(:,:,app.counter);
                app.ftext.String=int2str(app.counter);
                pause(0.1);
                if app.counter==app.nF
                    app.counter=1;
                else
                    app.counter=app.counter+1;
                end
            end
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            value = app.FrameSpinner.Value;
            app.lvol.Data=app.FrameLabels{value}>0;

        end

        % Value changed function: XSpinner_TX
        function XSpinner_TXValueChanged(app, event)
            value = app.XSpinner_TX.Value;
            atf=affinetform3d;
            atf.A(1,4)=value;
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atf);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.XSpinner_TX.Value=0;

        end

        % Value changed function: YSpinner_TX
        function YSpinner_TXValueChanged(app, event)
            value = app.YSpinner_TX.Value;
            atf=affinetform3d;
            atf.A(2,4)=value;
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atf);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.YSpinner_TX.Value=0;
        end

        % Value changed function: ZSpinner_TX
        function ZSpinner_TXValueChanged(app, event)
            value = app.ZSpinner_TX.Value;
            atf=affinetform3d;
            atf.A(3,4)=value;
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atf);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.ZSpinner_TX.Value=0;
        end

        % Value changed function: XSpinner_Rot
        function XSpinner_RotValueChanged(app, event)
            value = app.XSpinner_Rot.Value;
            if value==90
                value=89;
            end
            atf=affinetform3d;
            atf.A(2,2)=cosd(value);
            atf.A(3,2)=sind(value);
            atf.A(2,3)=-sind(value);
            atf.A(3,3)=cosd(value);
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atf);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.XSpinner_Rot.Value=0;
        end

        % Value changed function: YSpinner_Rot
        function YSpinner_RotValueChanged(app, event)
            value = app.YSpinner_Rot.Value;
            if value==90
                value=89;
            end
            atf=affinetform3d;
            atf.A(1,1)=cosd(value);
            atf.A(1,3)=sind(value);
            atf.A(3,1)=-sind(value);
            atf.A(3,3)=cosd(value);
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atf);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.YSpinner_Rot.Value=0;
        end

        % Value changed function: ZSpinner_Rot
        function ZSpinner_RotValueChanged(app, event)
            value = app.ZSpinner_Rot.Value;
            if value==90
                value=89;
            elseif value==-90
                value=-89;
            end
            atf=affinetform3d;
            atf.A(1,1)=cosd(value);
            atf.A(2,1)=sind(value);
            atf.A(1,2)=-sind(value);
            atf.A(2,2)=cosd(value);
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atf);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.ZSpinner_Rot.Value=0;
        end

        % Value changed function: xaxisSwitch
        function xaxisSwitchValueChanged(app, event)
            %value = app.xaxisSwitch.Value;
            atfref=affinetform3d;
            atfref.A(1,1)=-atfref.A(1,1);
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atfref);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
        end

        % Value changed function: yaxisSwitch
        function yaxisSwitchValueChanged(app, event)
            %value = app.yaxisSwitch.Value;
            atfref=affinetform3d;
            atfref.A(2,2)=-atfref.A(2,2);
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atfref);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
        end

        % Value changed function: zaxisSwitch
        function zaxisSwitchValueChanged(app, event)
            %value = app.zaxisSwitch.Value;
            atfref=affinetform3d;
            atfref.A(3,3)=-atfref.A(3,3);
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atfref);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
        end

        % Value changed function: XSpinner_Sca
        function Spinner_ScaleValueChanged(app, event)
            value = app.XSpinner_Sca.Value;
            atfsca=affinetform3d;
            if app.syncaxesCheckBox.Value
                atfsca.A(1,1)=value;
                atfsca.A(2,2)=value;
                atfsca.A(3,3)=value;
            else
                atfsca.A(1,1)=value;
            end
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atfsca);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.XSpinner_Sca.Value=1;
        end

        % Value changed function: YSpinner_Sca
        function YSpinner_ScaValueChanged(app, event)
            value = app.YSpinner_Sca.Value;
            atfsca=affinetform3d;
            if app.syncaxesCheckBox.Value
                atfsca.A(1,1)=value;
                atfsca.A(2,2)=value;
                atfsca.A(3,3)=value;
            else
                atfsca.A(2,2)=value;
            end
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atfsca);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.YSpinner_Sca.Value=1;
        end

        % Value changed function: ZSpinner_Sca
        function ZSpinner_ScaValueChanged(app, event)
            value = app.ZSpinner_Sca.Value;
            atfsca=affinetform3d;
            if app.syncaxesCheckBox.Value
                atfsca.A(1,1)=value;
                atfsca.A(2,2)=value;
                atfsca.A(3,3)=value;
            else
                atfsca.A(3,3)=value;
            end
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atfsca);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.ZSpinner_Sca.Value=1;
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.pcReg=app.pcRegReset;
            Regcur=app.pcReg;
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
        end

        % Button pushed function: LOADNEURONSButton
        function LOADNEURONSButtonPushed(app, event)
            %%% Initialize lists  and graphs %%%%%%%%%%%%%%
            cla(app.UIAxes_MI);
            cla(app.UIAxes_Location);
            cla(app.UIAxes_GCaMP);
            app.ListBox_Filtered.Items={};
            app.ListBox_Filtered.ItemsData=[];
            app.ListBox_Selected.Items={};
            app.ListBox_Selected.ItemsData=[];
            app.UITable.Data=[];
            app.AssignNeurons=cell(app.nN,3); % cell array to store data for export
            app.neuroP=[];

            %%% Exp neurons vector calculations %%%%%%%%%%%%
            AntNRef=app.RefCoordHead(77,:); % most anterior ref neuron

            ExpHead=app.pcReg.Location;
            [~, dant]=knnsearch(AntNRef,ExpHead,"K",1);
            if dant>10
                ExpHead=[AntNRef;ExpHead];
                nNadj=app.nN+1;
            else
                nNadj=app.nN;
            end
            EXPM=zeros(nNadj,3,nNadj);
            for i=1:nNadj
                k=ExpHead(i,:);
                EXPM(:,:,i)=k-ExpHead;
            end
            EXPMC=mat2cell(EXPM,nNadj,3,ones(nNadj,1));
            app.ExpVectorCellArray=cellfun(@(x) x(all(x,2),:),...
                EXPMC,'UniformOutput',false);
            app.ExpVectorCellArray=squeeze(app.ExpVectorCellArray);
            app.ExpVectorCellArray(1)=[];
            %%% Load exp neurons data %%%%%%%%%%%%%%%%%%%%%%
            for i=1:app.nN
                T=app.gCaMPData{i};
                %T(:,[1 2])=[];
                app.neuronS(i).NeuronProperties=T;
                D=pdist2(T.Centroid(1,:),T.Centroid);
                m=max(D);
                s=std(D);
                DLim=m+s+5;
                Cur=app.pcReg.Location(i,:);
                [idx, dclose]=knnsearch(app.pcRef.Location,Cur,'K',50);
                tf=dclose>DLim;
                dclose(tf)=[];idx(tf)=[];
                ddclose=sum(dclose)-dclose;
                stats=ddclose/sum(ddclose);
                IDNx=app.IDN(idx);
                refheadx=app.RefCoordHead(idx,:);
                app.neuronS(i).NeuronID=table(IDNx,dclose',stats',refheadx,...
                    'VariableNames',...
                    {'ID','Distance','Probability','Centroid'});

            end

            %%%  Initialize  plots and data %%%%%%%%%%%%%%%%%%%%%%%
            app.UIAxes_MI.XLabel.String='Time (#frames)';
            app.UIAxes_MI.YLabel.String='Mean gCaMP intensity';

            app.himca=imagesc(app.Gstack(:,:,1),...
                'Parent',app.UIAxes_GCaMP);
            colormap(app.UIAxes_GCaMP,'hot');
            clim(app.UIAxes_GCaMP,[app.qimi app.qim]);
            axis(app.UIAxes_GCaMP,'equal');
            hold(app.UIAxes_GCaMP,'on');
            app.LOADNEURONSButton.Enable="off";
            app.NP={app.neuronS.NeuronProperties}';
            app.NID={app.neuronS.NeuronID}';
            app.NPS=vertcat(app.NP{:,1});
            app.sneuronMI=cellfun(@(x)  x.MeanIntensity,app.NP,...
                'UniformOutput',false);
            app.sneuronNorm=cellfun(@(x) x.NormIntensity,app.NP,...
                'UniformOutput',false);
            app.mami=max(app.NPS.MeanIntensity);
            app.smami=cellfun(@max,app.sneuronMI);
            app.smanorm=cellfun(@max,app.sneuronNorm);
            app.mimi=min(app.NPS.MeanIntensity);
            app.avmi=mean(app.NPS.MeanIntensity);
            app.IntensitymaxSpinner.Value=app.avmi;
            tf=app.smami>app.avmi & app.smanorm>0;

            numN=sum(tf);
            app.nfind=find(tf);
            app.Label_6.Text=[int2str(numN) '/' int2str(app.nN)];


            enumN=int2str(app.nfind);
            app.elist=cellstr([repmat('Neuron#',numN,1) enumN]);
            app.ListBox_Filtered.Items=app.elist;
            %app.ListBox_Filtered.Value=elist{1};
            %             app.ListBox_Filtered.ItemsData=app.NP(tf);
            app.ListBox_Filtered.ItemsData=app.nfind;

            app.UIAxes_MI.YLim=[app.mimi-10 app.mami+10];
            plot([1 app.nF],[app.avmi app.avmi],...
                '-k','Parent',app.UIAxes_MI);
            hold(app.UIAxes_MI,'on');


        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab==app.REGISTRATIONTab
                app.LOADNEURONSButton.Enable="on";
            end
        end

        % Button pushed function: XYButton
        function XYButtonPushed(app, event)
            app.UIAxes.View=[0,90];
        end

        % Button pushed function: XZButton
        function XZButtonPushed(app, event)
            app.UIAxes.View=[0, 0];
        end

        % Button pushed function: YZButton
        function YZButtonPushed(app, event)
            app.UIAxes.View=[90, 0];
        end

        % Button pushed function: HIDEButton
        function HIDEButtonPushed(app, event)
           
            
            if app.pcState==true
                app.pcState=false;
                app.HIDEButton.Text='SHOW';
                app.hreg.AlphaData(app.ndopid)=0;
                
                app.href.AlphaData(app.nRefdopid)=0;
            elseif app.pcState==false
                app.pcState=true;
                app.HIDEButton.Text='HIDE';
                app.hreg.AlphaData(app.ndopid)=0.2;
                app.href.AlphaData(app.nRefdopid)=0.2;
            end

        end

        % Value changed function: AmplitudestdSpinner
        function AmplitudestdSpinnerValueChanged(app, event)
            value = app.AmplitudestdSpinner.Value;
            tf=app.smami>app.IntensitymaxSpinner.Value & ...
                app.smanorm>value &...
                app.nFramesNeurons>app.tLength;
            numN=sum(tf);
            app.nfind=find(tf);
            app.Label_6.Text=[int2str(numN) '/' int2str(app.nN)];
            %NPScur=app.NPS(tf,1);
            enumN=int2str(app.nfind);
            app.elist=cellstr([repmat('Neuron#',numN,1) enumN]);
            app.ListBox_Filtered.Items=app.elist;
            %app.ListBox_Filtered.Value=elist{1};
            app.ListBox_Filtered.ItemsData=app.nfind;
            cla(app.UIAxes_MI);
            app.UIAxes_MI.YLim=[app.mimi-10 app.mami+10];drawnow;
            hold(app.UIAxes_MI,'on');
            plot([1 app.nF],[app.avmi app.avmi],...
                '-k','Parent',app.UIAxes_MI);


        end

        % Value changed function: IntensitymaxSpinner
        function IntensitymaxSpinnerValueChanged(app, event)
            value = app.IntensitymaxSpinner.Value;
            tf=app.smami>value & ...
                app.smanorm>app.AmplitudestdSpinner.Value &...
                app.nFramesNeurons>app.tLength;
            numN=sum(tf);
            app.nfind=find(tf);
            app.Label_6.Text=[int2str(numN) '/' int2str(app.nN)];
            %NPScur=app.NPS(tf,1);
            enumN=int2str(app.nfind);
            app.elist=cellstr([repmat('Neuron#',numN,1) enumN]);
            app.ListBox_Filtered.Items=app.elist;
            %app.ListBox_Filtered.Value=elist{1};
            app.ListBox_Filtered.ItemsData=app.nfind;
            cla(app.UIAxes_MI);
            app.UIAxes_MI.YLim=[app.mimi-10 app.mami+10];drawnow;
            hold(app.UIAxes_MI,'on');
            plot([1 app.nF],[app.avmi app.avmi],...
                '-k','Parent',app.UIAxes_MI);

        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            if ~isempty(app.neuroP)
                for i=1:app.nF
                    delete(findobj(app.UIAxes_GCaMP.Children,'Type','Scatter'));
                    app.himca.CData=app.Gstack(:,:,i);
                    k=find(app.neuroP.FrameID==i);
                    if ~isempty(k)
                        scatter(app.neuroP.OrigCentroid(k,1),app.neuroP.OrigCentroid(k,2),...
                            'og','LineWidth',1,'Parent',app.UIAxes_GCaMP);
                    end
                    pause(0.1);
                end
            end
        end

        % Double-clicked callback: ListBox_Filtered
        function ListBox_FilteredDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            addidx=app.nfind(item);
            additem={['Neuron#' int2str(addidx)]};
            app.ListBox_Selected.Items=...
                [app.ListBox_Selected.Items, additem];
            app.ListBox_Selected.ItemsData=...
                [app.ListBox_Selected.ItemsData, addidx];
            app.neuroP=app.NP{addidx};
            FrameID=fillVector(app.neuroP.FrameID);
            nfrid=numel(FrameID);
            MeanIntensity=nan(size(FrameID));
            countfrid=1;
            for i=1:nfrid
                if ~isnan(FrameID(i))
                    MeanIntensity(i)=app.neuroP.MeanIntensity(countfrid);
                    countfrid=countfrid+1;
                end
            end

            app.AssignNeurons{addidx,1}=[app.neuroP.FrameID, app.neuroP.MeanIntensity];
            app.AssignNeurons{addidx,2}=app.NID{addidx};
        end

        % Clicked callback: ListBox_Filtered
        function ListBox_FilteredClicked(app, event)
            item = event.InteractionInformation.Item;
            app.neurovalue=app.ListBox_Filtered.ItemsData(item);

            app.PLAYButton.Enable="on";
            cla(app.UIAxes_MI);
            app.UIAxes_MI.YLim=[app.mimi-10 app.mami+10];
            plot([1 app.nF],[app.avmi app.avmi],...
                '-k','Parent',app.UIAxes_MI);
            hold(app.UIAxes_MI,'on');
            app.neuroP=app.NP{app.neurovalue};
            FrameID=fillVector(app.neuroP.FrameID);
            nfrid=numel(FrameID);
            MeanIntensity=nan(size(FrameID));
            countfrid=1;
            for i=1:nfrid
                if ~isnan(FrameID(i))
                    MeanIntensity(i)=app.neuroP.MeanIntensity(countfrid);
                    countfrid=countfrid+1;
                end
            end
            plot(FrameID,MeanIntensity,...
                '-c','Linewidth',2,'Parent',app.UIAxes_MI)
            %app.AssignNeurons{neurovalue,1}=[app.neuroP.FrameID, app.neuroP.MeanIntensity];

            %%% switch analysis depending on algo %%%%%%%%%%%%

            switch app.IdentificationalgorithmSwitch.Value
                case '0'
                    app.UITable.Data=[];
                    IDSelect=app.NID{app.neurovalue};
                    app.UITable.Data=IDSelect(:,1:3);

                    cla(app.UIAxes_Location);
                    Cent=app.pcReg.Location(app.neurovalue,:);
                    CentRef=IDSelect.Centroid;
                    regcl=app.pcReg;
                    regcl=regcl.Location;
                    app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                        app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.2,...
                        'Parent',app.UIAxes_Location);
                    axis(app.UIAxes_Location,'equal');
                    hold(app.UIAxes_Location,'on');
                    app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                        'm','filled','MarkerFaceAlpha',0.2,...
                        'Parent',app.UIAxes_Location);
                    app.UIAxes_Location.Color=[0.2 0.2 0.2];
                    app.UIAxes_Location.GridColor=[1 1 1];
                    grid(app.UIAxes_Location,'on')
                    app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                        'Parent',app.UIAxes_Location);
                    app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                        72,'g','filled','Parent',app.UIAxes_Location);
                    text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                        CentRef(:,3),IDSelect.ID,'Color','w');
                    app.UIAxes_Location.XLim=[Cent(1)-25 Cent(1)+25];
                    app.UIAxes_Location.YLim=[Cent(2)-10 Cent(2)+10];
                    app.UIAxes_Location.ZLim=[Cent(3)-10 Cent(3)+10];
                    %app.AssignNeurons{neurovalue,2}=IDSelect;

                case '1'
                    app.UITable.Data=[];
                    if ~isempty(app.AssignNeurons{app.neurovalue,3})
                        app.UITable.Data=app.AssignNeurons{app.neurovalue,3}(1,1:3);
                        CentRef=app.AssignNeurons{app.neurovalue,3}.Centroid;
                        assneuronID=app.AssignNeurons{app.neurovalue,3}.ID;
                        cla(app.UIAxes_Location);
                        Cent=app.pcReg.Location(app.neurovalue,:);
                        regcl=app.pcReg;
                        regcl=regcl.Location;
                        app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                            app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.5,...
                            'Parent',app.UIAxes_Location);
                        axis(app.UIAxes_Location,'equal');
                        hold(app.UIAxes_Location,'on');
                        app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                            'm','filled','MarkerFaceAlpha',0.5,...
                            'Parent',app.UIAxes_Location);
                        app.UIAxes_Location.Color=[0.2 0.2 0.2];
                        app.UIAxes_Location.GridColor=[1 1 1];
                        grid(app.UIAxes_Location,'on')
                        app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                            'Parent',app.UIAxes_Location);
                        app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                            72,'g','filled','Parent',app.UIAxes_Location);
                        text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                            CentRef(:,3),assneuronID,'Color','w');
                        app.UIAxes_Location.XLim=[min([Cent(1) CentRef(1)])-25,...
                            max([Cent(1) CentRef(1)])+25];
                        app.UIAxes_Location.YLim=[min([Cent(2) CentRef(2)])-10,...
                            max([Cent(2) CentRef(2)])+10];
                        app.UIAxes_Location.ZLim=[min([Cent(3) CentRef(3)])-10,...
                            max([Cent(3) CentRef(3)])+10];
                    else
                        CurExpVector=app.ExpVectorCellArray{app.neurovalue};
                        oldpointer=app.UIFigure.Pointer;
                        app.UIFigure.Pointer='Watch';drawnow;
                        data=computeassignment(CurExpVector,...
                            app.RefVectorCellArray);
                        app.UIFigure.Pointer=oldpointer;drawnow;
                        [~, midx]=min(data(:,2));
                        CentRef=app.refcl(data(midx,1),:);
                        cla(app.UIAxes_Location);
                        Cent=app.pcReg.Location(app.neurovalue,:);
                        regcl=app.pcReg;
                        regcl=regcl.Location;
                        app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                            app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.2,...
                            'Parent',app.UIAxes_Location);
                        axis(app.UIAxes_Location,'equal');
                        hold(app.UIAxes_Location,'on');
                        app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                            'm','filled','MarkerFaceAlpha',0.2,...
                            'Parent',app.UIAxes_Location);
                        app.UIAxes_Location.Color=[0.2 0.2 0.2];
                        app.UIAxes_Location.GridColor=[1 1 1];
                        grid(app.UIAxes_Location,'on')
                        app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                            'Parent',app.UIAxes_Location);
                        app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                            72,'g','filled','Parent',app.UIAxes_Location);
                        text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                            CentRef(:,3),app.IDN(data(midx,1)),'Color','w');
                        app.UIAxes_Location.XLim=[min([Cent(1) CentRef(1)])-25,...
                            max([Cent(1) CentRef(1)])+25];
                        app.UIAxes_Location.YLim=[min([Cent(2) CentRef(2)])-10,...
                            max([Cent(2) CentRef(2)])+10];
                        app.UIAxes_Location.ZLim=[min([Cent(3) CentRef(3)])-10,...
                            max([Cent(3) CentRef(3)])+10];
                        distm=[Cent;CentRef];
                        ddistm=pdist(distm);
                        IDSelect=table(app.IDN(data(midx,1)),ddistm,NaN,CentRef,...
                            'VariableNames',{'ID','Distance','Probability','Centroid'});
                        app.UITable.Data=IDSelect(1,1:3);
                        %app.AssignNeurons{neurovalue,3}=IDSelect;
                    end

            end
        end

        % Clicked callback: ListBox_Selected
        function ListBox_SelectedClicked(app, event)
            item = event.InteractionInformation.Item;
            app.neurovalue=app.ListBox_Selected.ItemsData(item);

            cla(app.UIAxes_MI);
            app.UIAxes_MI.YLim=[app.mimi-10 app.mami+10];
            plot([1 app.nF],[app.avmi app.avmi],...
                '-k','Parent',app.UIAxes_MI);
            hold(app.UIAxes_MI,'on');
            app.neuroP=app.NP{app.neurovalue};
            FrameID=fillVector(app.neuroP.FrameID);
            nfrid=numel(FrameID);
            MeanIntensity=nan(size(FrameID));
            countfrid=1;
            for i=1:nfrid
                if ~isnan(FrameID(i))
                    MeanIntensity(i)=app.neuroP.MeanIntensity(countfrid);
                    countfrid=countfrid+1;
                end
            end
            plot(FrameID,MeanIntensity,...
                '-c','Linewidth',2,'Parent',app.UIAxes_MI)
            %app.AssignNeurons{app.neurovalue,1}=[app.neuroP.FrameID, app.neuroP.MeanIntensity];
            %%% switch analysis depending on algo %%%%%%%%%%%%

            switch app.IdentificationalgorithmSwitch.Value
                case '0'
                    app.UITable.Data=[];
                    IDSelect=app.NID{app.neurovalue};
                    app.UITable.Data=IDSelect(:,1:3);

                    cla(app.UIAxes_Location);
                    Cent=app.pcReg.Location(app.neurovalue,:);
                    CentRef=IDSelect.Centroid;
                    regcl=app.pcReg;
                    regcl=regcl.Location;
                    app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                        app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.2,...
                        'Parent',app.UIAxes_Location);
                    axis(app.UIAxes_Location,'equal');
                    hold(app.UIAxes_Location,'on');
                    app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                        'm','filled','MarkerFaceAlpha',0.2,...
                        'Parent',app.UIAxes_Location);
                    app.UIAxes_Location.Color=[0.2 0.2 0.2];
                    app.UIAxes_Location.GridColor=[1 1 1];
                    grid(app.UIAxes_Location,'on')
                    app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                        'Parent',app.UIAxes_Location);
                    app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                        72,'g','filled','Parent',app.UIAxes_Location);
                    text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                        CentRef(:,3),IDSelect.ID,'Color','w');
                    app.UIAxes_Location.XLim=[Cent(1)-25 Cent(1)+25];
                    app.UIAxes_Location.YLim=[Cent(2)-10 Cent(2)+10];
                    app.UIAxes_Location.ZLim=[Cent(3)-10 Cent(3)+10];

                case '1'
                    app.UITable.Data=[];
                    if ~isempty(app.AssignNeurons{app.neurovalue,3})
                        app.UITable.Data=app.AssignNeurons{app.neurovalue,3}(1,1:3);
                        CentRef=app.AssignNeurons{app.neurovalue,3}.Centroid;
                        assneuronID=app.AssignNeurons{app.neurovalue,3}.ID;
                        cla(app.UIAxes_Location);
                        Cent=app.pcReg.Location(app.neurovalue,:);
                        regcl=app.pcReg;
                        regcl=regcl.Location;
                        app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                            app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.5,...
                            'Parent',app.UIAxes_Location);
                        axis(app.UIAxes_Location,'equal');
                        hold(app.UIAxes_Location,'on');
                        app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                            'm','filled','MarkerFaceAlpha',0.5,...
                            'Parent',app.UIAxes_Location);
                        app.UIAxes_Location.Color=[0.2 0.2 0.2];
                        app.UIAxes_Location.GridColor=[1 1 1];
                        grid(app.UIAxes_Location,'on')
                        app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                            'Parent',app.UIAxes_Location);
                        app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                            72,'g','filled','Parent',app.UIAxes_Location);
                        text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                            CentRef(:,3),assneuronID,'Color','w');
                        app.UIAxes_Location.XLim=[min([Cent(1) CentRef(1)])-25,...
                            max([Cent(1) CentRef(1)])+25];
                        app.UIAxes_Location.YLim=[min([Cent(2) CentRef(2)])-10,...
                            max([Cent(2) CentRef(2)])+10];
                        app.UIAxes_Location.ZLim=[min([Cent(3) CentRef(3)])-10,...
                            max([Cent(3) CentRef(3)])+10];
                    else
                        CurExpVector=app.ExpVectorCellArray{app.neurovalue};
                        oldpointer=app.UIFigure.Pointer;
                        app.UIFigure.Pointer='Watch';drawnow;
                        data=computeassignment(CurExpVector,...
                            app.RefVectorCellArray);
                        app.UIFigure.Pointer=oldpointer;drawnow;
                        [~, midx]=min(data(:,2));
                        CentRef=app.refcl(data(midx,1),:);
                        cla(app.UIAxes_Location);
                        Cent=app.pcReg.Location(app.neurovalue,:);
                        regcl=app.pcReg.Location;
                        app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                            app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.5,...
                            'Parent',app.UIAxes_Location);
                        axis(app.UIAxes_Location,'equal');
                        hold(app.UIAxes_Location,'on');
                        app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                            'm','filled','MarkerFaceAlpha',0.5,...
                            'Parent',app.UIAxes_Location);
                        app.UIAxes_Location.Color=[0.2 0.2 0.2];
                        app.UIAxes_Location.GridColor=[1 1 1];
                        grid(app.UIAxes_Location,'on')
                        app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                            'Parent',app.UIAxes_Location);
                        app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                            72,'g','filled','Parent',app.UIAxes_Location);
                        text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                            CentRef(:,3),app.IDN(data(midx,1)),'Color','w');
                        app.UIAxes_Location.XLim=[min([Cent(1) CentRef(1)])-25,...
                            max([Cent(1) CentRef(1)])+25];
                        app.UIAxes_Location.YLim=[min([Cent(2) CentRef(2)])-10,...
                            max([Cent(2) CentRef(2)])+10];
                        app.UIAxes_Location.ZLim=[min([Cent(3) CentRef(3)])-10,...
                            max([Cent(3) CentRef(3)])+10];
                        distm=[Cent;CentRef];
                        ddistm=pdist(distm);
                        IDSelect=table(app.IDN(data(midx,1)),ddistm,NaN,CentRef,...
                            'VariableNames',{'ID','Distance','Probability','Centroid'});
                        app.AssignNeurons{app.neurovalue,3}=IDSelect;
                        app.UITable.Data=IDSelect(1,1:3);
                    end

            end

        end

        % Double-clicked callback: ListBox_Selected
        function ListBox_SelectedDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            app.ListBox_Selected.Items(item)=[];
            app.ListBox_Selected.ItemsData(item)=[];
            app.AssignNeurons(app.ListBox_Selected.ItemsData(item),:)=[];
        end

        % Button pushed function: SNAPButton
        function SNAPButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            app.UIFigure.Visible='off';
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportapp(app.UIFigure,[filepath filename]);
            end
            app.UIFigure.Visible='on';

        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)

            tf=cellfun(@isempty,app.AssignNeurons);
            k=find(~tf(:,1));
            filter = {'*.xlsx'};
            app.UIFigure.Visible='off';
            [filename,filepath] = uiputfile(filter);
            app.UIFigure.Visible='on';
            oldpointer=app.UIFigure.Pointer;
            app.UIFigure.Pointer='Watch';drawnow;
            filenameID=[filename(1:end-5) 'ID.xlsx' ];
            if ischar(filename)
                for i=1:numel(k)

                    writetable(array2table(app.AssignNeurons{k(i),1},...
                        "VariableNames",{'Frame#','MeanIntensity'}),...
                        [filepath,filename],...
                        'sheet',['Neuron#' int2str(k(i))]);
                    if isempty(app.AssignNeurons{k(i),3})
                        data=computeassignment(app.ExpVectorCellArray{k(i)},...
                            app.RefVectorCellArray);
                        [~, midx]=min(data(:,2));
                        CentRef=app.refcl(data(midx,1),:);
                        Cent=app.pcReg.Location(k(i),:);
                        ddist=pdist([Cent;CentRef]);
                        app.AssignNeurons{k(i),3}=...
                            table(app.IDN(data(midx,1)),ddist,NaN,CentRef,...
                            'VariableNames',{'ID','Distance','Probability','Centroid'});
                    end
                    IDfast=app.AssignNeurons{k(i),2};
                    IDfast.Centroid=[];
                    %IDslow=array2table(app.AssignNeurons{k(i),3}(1,1:3),...
                    %'VariableNames',{'ID','Distance','Probability'});
                    IDslow=app.AssignNeurons{k(i),3};
                    IDslow.Centroid=[];
                    C=[IDfast;IDslow];
                    writetable(C,[filepath filenameID],'sheet',['Neuron#' int2str(k(i))]);
                end
                app.UIFigure.Pointer=oldpointer;

            end
        end

        % Value changed function: TracklengthSpinner
        function TracklengthSpinnerValueChanged(app, event)
            value = app.TracklengthSpinner.Value;
            app.tLength=round((value/100)*app.nF);
            tf=app.smami>app.IntensitymaxSpinner.Value & ...
                app.smanorm>app.AmplitudestdSpinner.Value & ...
                app.nFramesNeurons>app.tLength;
            numN=sum(tf);
            app.nfind=find(tf);
            app.Label_6.Text=[int2str(numN) '/' int2str(app.nN)];
            %NPScur=app.NPS(tf,1);
            enumN=int2str(app.nfind);
            app.elist=cellstr([repmat('Neuron#',numN,1) enumN]);
            app.ListBox_Filtered.Items=app.elist;
            %app.ListBox_Filtered.Value=elist{1};
            app.ListBox_Filtered.ItemsData=app.nfind;
            %             cla(app.UIAxes_MI);
            %             app.UIAxes_MI.YLim=[app.mimi-10 app.mami+10];drawnow;
            %             hold(app.UIAxes_MI,'on');
            %             plot([1 app.nF],[app.avmi app.avmi],...
            %                 '-k','Parent',app.UIAxes_MI);

        end

        % Value changed function: IdentificationalgorithmSwitch
        function IdentificationalgorithmSwitchValueChanged(app, event)
            value = app.IdentificationalgorithmSwitch.Value;
            if ~isempty(app.neurovalue)
                switch value
                    case '0'
                        app.UITable.Data=[];
                        IDSelect=app.NID{app.neurovalue};
                        app.UITable.Data=IDSelect(:,1:3);

                        cla(app.UIAxes_Location);
                        Cent=app.pcReg.Location(app.neurovalue,:);
                        CentRef=IDSelect.Centroid;
                        regcl=app.pcReg;
                        regcl=regcl.Location;
                        app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                            app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.2,...
                            'Parent',app.UIAxes_Location);
                        axis(app.UIAxes_Location,'equal');
                        hold(app.UIAxes_Location,'on');
                        app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                            'm','filled','MarkerFaceAlpha',0.2,...
                            'Parent',app.UIAxes_Location);
                        app.UIAxes_Location.Color=[0.2 0.2 0.2];
                        app.UIAxes_Location.GridColor=[1 1 1];
                        grid(app.UIAxes_Location,'on')
                        app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                            'Parent',app.UIAxes_Location);
                        app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                            72,'g','filled','Parent',app.UIAxes_Location);
                        text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                            CentRef(:,3),IDSelect.ID,'Color','w');
                        app.UIAxes_Location.XLim=[Cent(1)-25 Cent(1)+25];
                        app.UIAxes_Location.YLim=[Cent(2)-10 Cent(2)+10];
                        app.UIAxes_Location.ZLim=[Cent(3)-10 Cent(3)+10];

                    case '1'
                        app.UITable.Data=[];
                        if ~isempty(app.AssignNeurons{app.neurovalue,3})
                            app.UITable.Data=app.AssignNeurons{app.neurovalue,3}(1,1:3);
                            CentRef=app.AssignNeurons{app.neurovalue,3}.Centroid;
                            assneuronID=app.AssignNeurons{app.neurovalue,3}.ID;
                            cla(app.UIAxes_Location);
                            Cent=app.pcReg.Location(app.neurovalue,:);
                            regcl=app.pcReg;
                            regcl=regcl.Location;
                            app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                                app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.5,...
                                'Parent',app.UIAxes_Location);
                            axis(app.UIAxes_Location,'equal');
                            hold(app.UIAxes_Location,'on');
                            app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                                'm','filled','MarkerFaceAlpha',0.5,...
                                'Parent',app.UIAxes_Location);
                            app.UIAxes_Location.Color=[0.2 0.2 0.2];
                            app.UIAxes_Location.GridColor=[1 1 1];
                            grid(app.UIAxes_Location,'on')
                            app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                                'Parent',app.UIAxes_Location);
                            app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                                72,'g','filled','Parent',app.UIAxes_Location);
                            text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                                CentRef(:,3),assneuronID,'Color','w');
                            app.UIAxes_Location.XLim=[min([Cent(1) CentRef(1)])-25,...
                                max([Cent(1) CentRef(1)])+25];
                            app.UIAxes_Location.YLim=[min([Cent(2) CentRef(2)])-10,...
                                max([Cent(2) CentRef(2)])+10];
                            app.UIAxes_Location.ZLim=[min([Cent(3) CentRef(3)])-10,...
                                max([Cent(3) CentRef(3)])+10];
                        else
                            CurExpVector=app.ExpVectorCellArray{app.neurovalue};
                            oldpointer=app.UIFigure.Pointer;
                            app.UIFigure.Pointer='Watch';drawnow;
                            data=computeassignment(CurExpVector,...
                                app.RefVectorCellArray);
                            app.UIFigure.Pointer=oldpointer;drawnow;
                            [~, midx]=min(data(:,2));
                            CentRef=app.refcl(data(midx,1),:);
                            cla(app.UIAxes_Location);
                            Cent=app.pcReg.Location(app.neurovalue,:);
                            regcl=app.pcReg;
                            regcl=regcl.Location;
                            app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                                app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.5,...
                                'Parent',app.UIAxes_Location);
                            axis(app.UIAxes_Location,'equal');
                            hold(app.UIAxes_Location,'on');
                            app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                                'm','filled','MarkerFaceAlpha',0.5,...
                                'Parent',app.UIAxes_Location);
                            app.UIAxes_Location.Color=[0.2 0.2 0.2];
                            app.UIAxes_Location.GridColor=[1 1 1];
                            grid(app.UIAxes_Location,'on')
                            app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                                'Parent',app.UIAxes_Location);
                            app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                                72,'g','filled','Parent',app.UIAxes_Location);
                            text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                                CentRef(:,3),app.IDN(data(midx,1)),'Color','w');
                            app.UIAxes_Location.XLim=[min([Cent(1) CentRef(1)])-25,...
                                max([Cent(1) CentRef(1)])+25];
                            app.UIAxes_Location.YLim=[min([Cent(2) CentRef(2)])-10,...
                                max([Cent(2) CentRef(2)])+10];
                            app.UIAxes_Location.ZLim=[min([Cent(3) CentRef(3)])-10,...
                                max([Cent(3) CentRef(3)])+10];
                            distm=[Cent;CentRef];
                            ddistm=pdist(distm);
                            IDSelect=table(app.IDN(data(midx,1)),ddistm,NaN,CentRef,...
                                'VariableNames',{'ID','Distance','Probability','Centroid'});
                            app.AssignNeurons{app.neurovalue,3}=IDSelect;
                            app.UITable.Data=IDSelect(1,1:3);
                        end

                end
            end
        end

        % Button pushed function: AUTOREGISTERButton
        function AUTOREGISTERButtonPushed(app, event)
            [~, app.pcReg]=pcregistericp(app.pcReg,app.pcRef);
            regcl=app.pcReg.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.REGISTRATIONTab = uitab(app.TabGroup);
            app.REGISTRATIONTab.Title = 'REGISTRATION';
            app.UIAxes_MP = uiaxes(app.REGISTRATIONTab);
            title(app.UIAxes_MP, 'gCaMP')
            app.UIAxes_MP.XTick = [];
            app.UIAxes_MP.YTick = [];
            app.UIAxes_MP.ZTick = [];
            app.UIAxes = uiaxes(app.REGISTRATIONTab);
            title(app.UIAxes, 'Registration')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RESETButton = uibutton(app.REGISTRATIONTab, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.AUTOREGISTERButton = uibutton(app.REGISTRATIONTab, 'push');
            app.AUTOREGISTERButton.ButtonPushedFcn = createCallbackFcn(app, @AUTOREGISTERButtonPushed, true);
            app.AUTOREGISTERButton.Text = 'AUTO REGISTER';
            app.TranslationPanel = uipanel(app.REGISTRATIONTab);
            app.TranslationPanel.Title = 'Translation';
            app.XSpinnerLabel = uilabel(app.TranslationPanel);
            app.XSpinnerLabel.Text = 'X-';
            app.XSpinner_TX = uispinner(app.TranslationPanel);
            app.XSpinner_TX.ValueChangedFcn = createCallbackFcn(app, @XSpinner_TXValueChanged, true);
            app.YSpinnerLabel = uilabel(app.TranslationPanel);
            app.YSpinnerLabel.Text = 'Y-';
            app.YSpinner_TX = uispinner(app.TranslationPanel);
            app.YSpinner_TX.ValueChangedFcn = createCallbackFcn(app, @YSpinner_TXValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.TranslationPanel);
            app.ZSpinnerLabel.Text = 'Z-';
            app.ZSpinner_TX = uispinner(app.TranslationPanel);
            app.ZSpinner_TX.ValueChangedFcn = createCallbackFcn(app, @ZSpinner_TXValueChanged, true);
            app.RotationPanel = uipanel(app.REGISTRATIONTab);
            app.RotationPanel.Title = 'Rotation';
            app.XSpinner_2Label = uilabel(app.RotationPanel);
            app.XSpinner_2Label.Text = 'X-';
            app.XSpinner_Rot = uispinner(app.RotationPanel);
            app.XSpinner_Rot.ValueChangedFcn = createCallbackFcn(app, @XSpinner_RotValueChanged, true);
            app.YSpinner_2Label = uilabel(app.RotationPanel);
            app.YSpinner_2Label.Text = 'Y-';
            app.YSpinner_Rot = uispinner(app.RotationPanel);
            app.YSpinner_Rot.ValueChangedFcn = createCallbackFcn(app, @YSpinner_RotValueChanged, true);
            app.ZSpinner_2Label = uilabel(app.RotationPanel);
            app.ZSpinner_2Label.Text = 'Z-';
            app.ZSpinner_Rot = uispinner(app.RotationPanel);
            app.ZSpinner_Rot.ValueChangedFcn = createCallbackFcn(app, @ZSpinner_RotValueChanged, true);
            app.ReflectionPanel = uipanel(app.REGISTRATIONTab);
            app.ReflectionPanel.Title = 'Reflection';
            app.xaxisSwitchLabel = uilabel(app.ReflectionPanel);
            app.xaxisSwitchLabel.Text = 'x-axis';
            app.xaxisSwitch = uiswitch(app.ReflectionPanel, 'slider');
            app.xaxisSwitch.ValueChangedFcn = createCallbackFcn(app, @xaxisSwitchValueChanged, true);
            app.yaxisSwitchLabel = uilabel(app.ReflectionPanel);
            app.yaxisSwitchLabel.Text = 'y-axis';
            app.yaxisSwitch = uiswitch(app.ReflectionPanel, 'slider');
            app.yaxisSwitch.ValueChangedFcn = createCallbackFcn(app, @yaxisSwitchValueChanged, true);
            app.zaxisSwitchLabel = uilabel(app.ReflectionPanel);
            app.zaxisSwitchLabel.Text = 'z-axis';
            app.zaxisSwitch = uiswitch(app.ReflectionPanel, 'slider');
            app.zaxisSwitch.ValueChangedFcn = createCallbackFcn(app, @zaxisSwitchValueChanged, true);
            app.ScalingPanel = uipanel(app.REGISTRATIONTab);
            app.ScalingPanel.Title = 'Scaling';
            app.XSpinnerLabel_2 = uilabel(app.ScalingPanel);
            app.XSpinnerLabel_2.Text = 'X-';
            app.XSpinner_Sca = uispinner(app.ScalingPanel);
            app.XSpinner_Sca.Step = 0.05;
            app.XSpinner_Sca.Limits = [0 Inf];
            app.XSpinner_Sca.ValueChangedFcn = createCallbackFcn(app, @Spinner_ScaleValueChanged, true);
            app.XSpinner_Sca.Value = 1;
            app.YLabel = uilabel(app.ScalingPanel);
            app.YLabel.Text = 'Y-';
            app.YSpinner_Sca = uispinner(app.ScalingPanel);
            app.YSpinner_Sca.Step = 0.05;
            app.YSpinner_Sca.Limits = [0 Inf];
            app.YSpinner_Sca.ValueChangedFcn = createCallbackFcn(app, @YSpinner_ScaValueChanged, true);
            app.YSpinner_Sca.Value = 1;
            app.ZLabel = uilabel(app.ScalingPanel);
            app.ZLabel.Text = 'Z-';
            app.ZSpinner_Sca = uispinner(app.ScalingPanel);
            app.ZSpinner_Sca.Step = 0.05;
            app.ZSpinner_Sca.Limits = [0 Inf];
            app.ZSpinner_Sca.ValueChangedFcn = createCallbackFcn(app, @ZSpinner_ScaValueChanged, true);
            app.ZSpinner_Sca.Value = 1;
            app.syncaxesCheckBox = uicheckbox(app.ScalingPanel);
            app.syncaxesCheckBox.Text = 'sync axes';
            app.RMSETextArea = uitextarea(app.REGISTRATIONTab);
            app.VolumeRenderingPanel = uipanel(app.REGISTRATIONTab);
            app.VolumeRenderingPanel.Title = 'Volume Rendering';
            app.Label_3 = uilabel(app.VolumeRenderingPanel);
            app.Label_3.Text = '';
            app.Button = uibutton(app.REGISTRATIONTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>';
            app.FrameSpinner = uispinner(app.REGISTRATIONTab);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Value = 1;
            app.Label_4 = uilabel(app.REGISTRATIONTab);
            app.Label_4.Text = '';
            app.HIDEButton = uibutton(app.REGISTRATIONTab, 'push');
            app.HIDEButton.ButtonPushedFcn = createCallbackFcn(app, @HIDEButtonPushed, true);
            app.HIDEButton.Text = 'HIDE';
            app.XYButton = uibutton(app.REGISTRATIONTab, 'push');
            app.XYButton.ButtonPushedFcn = createCallbackFcn(app, @XYButtonPushed, true);
            app.XYButton.Text = 'XY';
            app.XZButton = uibutton(app.REGISTRATIONTab, 'push');
            app.XZButton.ButtonPushedFcn = createCallbackFcn(app, @XZButtonPushed, true);
            app.XZButton.Text = 'XZ';
            app.YZButton = uibutton(app.REGISTRATIONTab, 'push');
            app.YZButton.ButtonPushedFcn = createCallbackFcn(app, @YZButtonPushed, true);
            app.YZButton.Text = 'YZ';
            app.IMPORTButton = uibutton(app.REGISTRATIONTab, 'push');
            app.IMPORTButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTButtonPushed, true);
            app.IMPORTButton.Text = 'IMPORT';
            app.ANALYSISTab = uitab(app.TabGroup);
            app.ANALYSISTab.Title = 'ANALYSIS';
            app.UIAxes_GCaMP = uiaxes(app.ANALYSISTab);
            title(app.UIAxes_GCaMP, 'gCaMP')
            xlabel(app.UIAxes_GCaMP, 'X')
            ylabel(app.UIAxes_GCaMP, 'Y')
            zlabel(app.UIAxes_GCaMP, 'Z')
            app.UIAxes_MI = uiaxes(app.ANALYSISTab);
            title(app.UIAxes_MI, 'Signal Intensity')
            xlabel(app.UIAxes_MI, 'X')
            ylabel(app.UIAxes_MI, 'Y')
            zlabel(app.UIAxes_MI, 'Z')
            app.UIAxes_Location = uiaxes(app.ANALYSISTab);
            title(app.UIAxes_Location, 'Location')
            xlabel(app.UIAxes_Location, 'X')
            ylabel(app.UIAxes_Location, 'Y')
            zlabel(app.UIAxes_Location, 'Z')
            app.LOADNEURONSButton = uibutton(app.ANALYSISTab, 'push');
            app.LOADNEURONSButton.ButtonPushedFcn = createCallbackFcn(app, @LOADNEURONSButtonPushed, true);
            app.LOADNEURONSButton.Text = 'LOAD NEURONS';
            app.AmplitudestdSpinnerLabel = uilabel(app.ANALYSISTab);
            app.AmplitudestdSpinnerLabel.Text = 'Amplitude (std)';
            app.AmplitudestdSpinner = uispinner(app.ANALYSISTab);
            app.AmplitudestdSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudestdSpinnerValueChanged, true);
            app.IntensitymaxSpinnerLabel = uilabel(app.ANALYSISTab);
            app.IntensitymaxSpinnerLabel.Text = 'Intensity (max)';
            app.IntensitymaxSpinner = uispinner(app.ANALYSISTab);
            app.IntensitymaxSpinner.ValueChangedFcn = createCallbackFcn(app, @IntensitymaxSpinnerValueChanged, true);
            app.ListBox_Filtered = uilistbox(app.ANALYSISTab);
            app.ListBox_Filtered.Items = {};
            app.ListBox_Filtered.ClickedFcn = createCallbackFcn(app, @ListBox_FilteredClicked, true);
            app.ListBox_Filtered.DoubleClickedFcn = createCallbackFcn(app, @ListBox_FilteredDoubleClicked, true);
            app.ListBox_Filtered.Value = {};
            app.UITable = uitable(app.ANALYSISTab);
            app.UITable.ColumnName = {'Neuron ID'; 'Distance'; 'Probability'};
            app.UITable.RowName = {};
            app.EXPORTButton = uibutton(app.ANALYSISTab, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.NeuronsLabel = uilabel(app.ANALYSISTab);
            app.NeuronsLabel.Text = '#Neurons:';
            app.Label_6 = uilabel(app.ANALYSISTab);
            app.Label_6.Text = '';
            app.ListBox_Selected = uilistbox(app.ANALYSISTab);
            app.ListBox_Selected.Items = {};
            app.ListBox_Selected.ClickedFcn = createCallbackFcn(app, @ListBox_SelectedClicked, true);
            app.ListBox_Selected.DoubleClickedFcn = createCallbackFcn(app, @ListBox_SelectedDoubleClicked, true);
            app.ListBox_Selected.Value = {};
            app.PLAYButton = uibutton(app.ANALYSISTab, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Enable = 'off';
            app.PLAYButton.Text = 'PLAY';
            app.IdentificationalgorithmSwitchLabel = uilabel(app.ANALYSISTab);
            app.IdentificationalgorithmSwitchLabel.Text = 'Identification algorithm';
            app.IdentificationalgorithmSwitch = uiswitch(app.ANALYSISTab, 'slider');
            app.IdentificationalgorithmSwitch.Items = {'Neighborhood (fast)', 'Assignment (slow)'};
            app.IdentificationalgorithmSwitch.ItemsData = {'0', '1'};
            app.IdentificationalgorithmSwitch.ValueChangedFcn = createCallbackFcn(app, @IdentificationalgorithmSwitchValueChanged, true);
            app.IdentificationalgorithmSwitch.Value = '0';
            app.FilteredNeuronsLabel = uilabel(app.ANALYSISTab);
            app.FilteredNeuronsLabel.Text = 'Filtered Neurons';
            app.SelectedNeuronsLabel = uilabel(app.ANALYSISTab);
            app.SelectedNeuronsLabel.Text = 'Selected Neurons';
            app.SNAPButton = uibutton(app.ANALYSISTab, 'push');
            app.SNAPButton.ButtonPushedFcn = createCallbackFcn(app, @SNAPButtonPushed, true);
            app.SNAPButton.Text = 'SNAP';
            app.TracklengthLabel = uilabel(app.ANALYSISTab);
            app.TracklengthLabel.Text = 'Track length (%)';
            app.TracklengthSpinner = uispinner(app.ANALYSISTab);
            app.TracklengthSpinner.Step = 10;
            app.TracklengthSpinner.Limits = [0 100];
            app.TracklengthSpinner.ValueChangedFcn = createCallbackFcn(app, @TracklengthSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SANIdentifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end