classdef SANIdentifierstatic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        REGISTRATIONTab                matlab.ui.container.Tab
        zsliceSpinnerLabel             matlab.ui.control.Label
        ChannelSpinnerLabel            matlab.ui.control.Label
        IMPORTButton                   matlab.ui.control.Button
        ChannelSpinner                 matlab.ui.control.Spinner
        zsliceSpinner                  matlab.ui.control.Spinner
        ContrastSlider                 matlab.ui.control.RangeSlider
        ContrastSliderLabel            matlab.ui.control.Label
        YZButton                       matlab.ui.control.Button
        XZButton                       matlab.ui.control.Button
        XYButton                       matlab.ui.control.Button
        HIDEButton                     matlab.ui.control.Button
        Label_4                        matlab.ui.control.Label
        VolumeRenderingPanel           matlab.ui.container.Panel
        Label_3                        matlab.ui.control.Label
        RMSETextArea                   matlab.ui.control.TextArea
        ScalingPanel                   matlab.ui.container.Panel
        syncaxesCheckBox               matlab.ui.control.CheckBox
        ZSpinner_Sca                   matlab.ui.control.Spinner
        ZLabel                         matlab.ui.control.Label
        YSpinner_Sca                   matlab.ui.control.Spinner
        YLabel                         matlab.ui.control.Label
        XSpinner_Sca                   matlab.ui.control.Spinner
        XSpinnerLabel_2                matlab.ui.control.Label
        ReflectionPanel                matlab.ui.container.Panel
        zaxisSwitch                    matlab.ui.control.Switch
        zaxisSwitchLabel               matlab.ui.control.Label
        yaxisSwitch                    matlab.ui.control.Switch
        yaxisSwitchLabel               matlab.ui.control.Label
        xaxisSwitch                    matlab.ui.control.Switch
        xaxisSwitchLabel               matlab.ui.control.Label
        RotationPanel                  matlab.ui.container.Panel
        ZSpinner_Rot                   matlab.ui.control.Spinner
        ZSpinner_2Label                matlab.ui.control.Label
        YSpinner_Rot                   matlab.ui.control.Spinner
        YSpinner_2Label                matlab.ui.control.Label
        XSpinner_Rot                   matlab.ui.control.Spinner
        XSpinner_2Label                matlab.ui.control.Label
        TranslationPanel               matlab.ui.container.Panel
        ZSpinner_TX                    matlab.ui.control.Spinner
        ZSpinnerLabel                  matlab.ui.control.Label
        YSpinner_TX                    matlab.ui.control.Spinner
        YSpinnerLabel                  matlab.ui.control.Label
        XSpinner_TX                    matlab.ui.control.Spinner
        XSpinnerLabel                  matlab.ui.control.Label
        AUTOREGISTERButton             matlab.ui.control.Button
        RESETButton                    matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_MP                      matlab.ui.control.UIAxes
        ANALYSISTab                    matlab.ui.container.Tab
        zsliceSpinner_2Label           matlab.ui.control.Label
        zsliceSpinner_2                matlab.ui.control.Spinner
        ChannelSpinner_2Label          matlab.ui.control.Label
        ChannelSpinner_2               matlab.ui.control.Spinner
        ContrastSlider_2               matlab.ui.control.RangeSlider
        LOADNEURONSButton              matlab.ui.control.Button
        FilteredNeuronsLabel           matlab.ui.control.Label
        ListBox_Filtered               matlab.ui.control.ListBox
        SelectedNeuronsLabel           matlab.ui.control.Label
        ListBox_Selected               matlab.ui.control.ListBox
        IdentificationalgorithmSwitch  matlab.ui.control.Switch
        SNAPButton                     matlab.ui.control.Button
        IdentificationalgorithmSwitchLabel  matlab.ui.control.Label
        EXPORTButton                   matlab.ui.control.Button
        EXPORTALLButton                matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        UIAxes_GCaMP                   matlab.ui.control.UIAxes
        UIAxes_Location                matlab.ui.control.UIAxes
        UIAxes_MI                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        RefCoordHead=[94.3400000000000	10.3100000000000	0.0300000000000000
            91.2200000000000	-11.6400000000000	-1.43000000000000
            87.9700000000000	10.5000000000000	-0.120000000000000
            83.2300000000000	-11.4700000000000	-0.580000000000000
            54.1800000000000	7	8.48000000000000
            54.6300000000000	-8.88000000000000	7.15000000000000
            59.1300000000000	3.86000000000000	12.0500000000000
            58.8000000000000	-6.34000000000000	11.3800000000000
            53.3700000000000	7.49000000000000	5.99000000000000
            53.1100000000000	-9.19000000000000	5.71000000000000
            66.7400000000000	0.660000000000000	-4.67000000000000
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            113.770000000000	1.06000000000000	-8.15000000000000
            132.470000000000	-1.74000000000000	-9.95000000000000]; % Head atlas coordinates

        IDN=["ADAL"
            "ADAR"
            "ADEL"
            "ADER"
            "ADFL"
            "ADFR"
            "ADLL"
            "ADLR"
            "AFDL"
            "AFDR"
            "AIAL"
            "AIAR"
            "AIBL"
            "AIBR"
            "AIML"
            "AIMR"
            "AINL"
            "AINR"
            "AIYL"
            "AIYR"
            "AIZL"
            "AIZR"
            "ALA"
            "AQR"
            "AS1"
            "ASEL"
            "ASER"
            "ASGL"
            "ASGR"
            "ASHL"
            "ASHR"
            "ASIL"
            "ASIR"
            "ASJL"
            "ASJR"
            "ASKL"
            "ASKR"
            "AUAL"
            "AUAR"
            "AVAL"
            "AVAR"
            "AVBL"
            "AVBR"
            "AVDL"
            "AVDR"
            "AVEL"
            "AVER"
            "AVFL"
            "AVFR"
            "AVG"
            "AVHL"
            "AVHR"
            "AVJL"
            "AVJR"
            "AVKL"
            "AVKR"
            "AVL"
            "AWAL"
            "AWAR"
            "AWBL"
            "AWBR"
            "AWCL"
            "AWCR"
            "BAGL"
            "BAGR"
            "CEPDL"
            "CEPDR"
            "CEPVL"
            "CEPVR"
            "DA1"
            "DB1"
            "DB2"
            "DD1"
            "FLPL"
            "FLPR"
            "I1L"
            "I1R"
            "I2L"
            "I2R"
            "I3"
            "I4"
            "I5"
            "I6"
            "IL1DL"
            "IL1DR"
            "IL1L"
            "IL1R"
            "IL1VL"
            "IL1VR"
            "IL2DL"
            "IL2DR"
            "IL2L"
            "IL2R"
            "IL2VL"
            "IL2VR"
            "M1"
            "M2L"
            "M2R"
            "M3L"
            "M3R"
            "M4"
            "M5"
            "MCL"
            "MCR"
            "MI"
            "NSML"
            "NSMR"
            "OLLL"
            "OLLR"
            "OLQDL"
            "OLQDR"
            "OLQVL"
            "OLQVR"
            "RIAL"
            "RIAR"
            "RIBL"
            "RIBR"
            "RICL"
            "RICR"
            "RID"
            "RIFL"
            "RIFR"
            "RIGL"
            "RIGR"
            "RIH"
            "RIML"
            "RIMR"
            "RIPL"
            "RIPR"
            "RIR"
            "RIS"
            "RIVL"
            "RIVR"
            "RMDDL"
            "RMDDR"
            "RMDL"
            "RMDR"
            "RMDVL"
            "RMDVR"
            "RMED"
            "RMEL"
            "RMER"
            "RMEV"
            "RMFL"
            "RMFR"
            "RMGL"
            "RMGR"
            "RMHL"
            "RMHR"
            "SAADL"
            "SAADR"
            "SAAVL"
            "SAAVR"
            "SABD"
            "SABVL"
            "SABVR"
            "SIADL"
            "SIADR"
            "SIAVL"
            "SIAVR"
            "SIBDL"
            "SIBDR"
            "SIBVL"
            "SIBVR"
            "SMBDL"
            "SMBDR"
            "SMBVL"
            "SMBVR"
            "SMDDL"
            "SMDDR"
            "SMDVL"
            "SMDVR"
            "URADL"
            "URADR"
            "URAVL"
            "URAVR"
            "URBL"
            "URBR"
            "URXL"
            "URXR"
            "URYDL"
            "URYDR"
            "URYVL"
            "URYVR"
            "VA1"
            "VB1"
            "VB2"
            "VD1"
            "VD2"]; % Neurons IDs

        % RefCoordDopa=[87.9700000000000	10.5000000000000	-0.120000000000000
        %     83.2300000000000	-11.4700000000000	-0.580000000000000
        %     49.5800000000000	3.70000000000000	13.6200000000000
        %     50.2800000000000	-7.07000000000000	12.9000000000000
        %     39.3400000000000	4.58000000000000	1.03000000000000
        %     40.6800000000000	-4.42000000000000	1.01000000000000]; % Coord dopaminergic neurons
        % IDDOPA=["ADEL"
        %     "ADER"
        %     "CEPDL"
        %     "CEPDR"
        %     "CEPVL"
        %     "CEPVR"]; % ID dopaminergic neurons
        %
        Refdopid=[3;4;66;67;68;69]; % indices of dopaminergic neurons in ref atlas
        nRefdopid % indices of non-dpomainergic neurons in ref atlas
        dopid %indices of dopaminergic neurons from experimental set
        ndopid %indices of non dopaminergic neurons in experimental set

        selpath % path to load/save data
        pd %dir structure of Labels mat files from SD
        pdg %dir structure of gCaMP channel stacks (green)
        pdb %dir sutructure of dopaminergic channel (blue)

        RefVectorCellArray % 189-by-1 cell array containing the xyz differential between each ref neurons
        % (188-by-3 matrix -self distanced neurons
        % deleted)
        ExpVectorCellArray % nExpNeurons-by-1 cell array containing xyz differental between each tracked
        % neurons ([nExpNeurons-1]-by-3 matrix)
        AssignNeurons % Neurons detected by assignment algo

        scale %[xy z] pixel physical sizes
        nZ % number of z-slices
        Gstack % gCaMP max Proj stack
        GBStack % 4D stack of green and blue channels
        cellStack % cell array containing raw image data (one cell per timeframe)
        FrameLabels % cell array containing Label objects

        gCaMPData % data structure from gCaMP channel regionprops
        CentOri % cell array containing original centroids, one cell per tracked neuron
        prepData % cell array containing registered coordinates
        nF% number of  time frames total
        nN % number of tracked neurons
        nFramesNeurons % track length for each neurons

        rmse %rmse value for scoring registration
        lvol %Volume redenring object
        counter = 1; %frame counter
        maxI % max intensities of G and B (2 by 1 vector)
        qim %0.99 quantile of gCaMP intensity and Dopa
        qimi %0.01 quantile of gCaMP intensity and Dopa
        himage % handle of gCaMP MP
        view3d %viewer3d object

        href  % handle  of scatter3 ref points
        hrefloc % handle  of scatter3 ref points analysis pane
        hreflocs % handle of scatter3 from selected neurons closest ref
        hreg % handle of scatter3 experimental points
        hregloc % handle of scatter3 experimental points analysis pane
        hreglocs % handle of scatter3 from selected experimental neuron
        ftext % frame string
        pcRef %head reaf point cloud
        pcMoving %point cloud from frame with highest cell number
        pcReg % point cloud of first icd registered pcMoving
        pcRegReset % reset pointCloud
        refcl % location of pcRef
        tform % transform matrix
        rsme1 %very first rsme value

        Imstate = false % state of Imshow
        pcState = true % state of showing/hiding non dopa pc
        % affine transformation object

        neuronS %final neuron structure
        neuroP % selected neurons from filtered list

        %% Tab Analysis %%

        mami % max mean intensity all neurons
        mimi % min mean intensity all  neurons
        avmi % average mean intensity  all neurons
        sneuronMI % cell array of  single neurons MI
        sneuronNorm % cell array of single neurons normalized intensity
        smami % max MI single neurons
        smanorm % max norm int neurons
        NP % cell array of neuron properties
        NID % cell array of neuronID
        NPS % concatenated Neuron Properties structure
        himca % handle for gCaMP tracking
        hstem % handle for stem plot of mean intensities
        elist % list of selected neurons in Filtered listbox
        %nfind % indices of filtered neurons
        nselect % indices of selected neurons
        tLength = 1; % minimum track length selected
        neurovalue % currently selected neuron
        MPairs % matched pairs using cost assignment

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IMPORTButton
        function IMPORTButtonPushed(app, event)

            %%%% app initialization and ref vector calculations %%%%%%%%%%
            app.counter=1;
            cla(app.UIAxes_MP);
            cla(app.UIAxes);
            delete(app.lvol);
            app.Label_3.Visible='on';
            app.Label_3.Text='';
            app.Label_4.Visible='on';
            app.Label_4.Text='';
            app.Imstate=false;
            app.xaxisSwitch.Value='Off';
            app.yaxisSwitch.Value='Off';
            app.zaxisSwitch.Value='Off';

            app.ListBox_Filtered.Items={''};
            app.ListBox_Filtered.ItemsData=[];
            app.ListBox_Selected.Items={''};
            app.ListBox_Selected.ItemsData=[];
            cla(app.UIAxes_MI);
            cla(app.UIAxes_GCaMP);
            app.UIAxes_MP.Title.Color= [1 1 1];
            app.UIAxes.Title.Color= [1 1 1];
            app.UIAxes.XLabel.Color=[1 1 1];
            app.UIAxes.YLabel.Color=[1 1 1];
            app.UIAxes.ZLabel.Color=[1 1 1];
            app.UIAxes.XColor=[1 1 1];
            app.UIAxes.YColor=[1 1 1];
            app.UIAxes.ZColor=[1 1 1];
            app.UIAxes.YDir='reverse';
            app.UIAxes_GCaMP.Title.Color=[1 1 1];

            axis(app.UIAxes_GCaMP,'off');
            app.UIAxes_MI.Title.Color=[1 1 1];
            app.UIAxes_MI.XLabel.Color=[1 1 1];
            app.UIAxes_MI.YLabel.Color=[1 1 1];

            app.UIAxes_MI.XColor=[1 1 1];
            app.UIAxes_MI.YColor=[1 1 1];
            app.UIAxes_Location.Title.Color=[1 1 1];
            app.UIAxes_Location.XLabel.Color=[1 1 1];
            app.UIAxes_Location.YLabel.Color=[1 1 1];
            app.UIAxes_Location.ZLabel.Color=[1 1 1];
            app.UIAxes_Location.XColor=[1 1 1];
            app.UIAxes_Location.YColor=[1 1 1];
            app.UIAxes_Location.ZColor=[1 1 1];

            app.ChannelSpinner.Value=1;
            app.ChannelSpinner.Limits=[1 2];

            children = get(app.REGISTRATIONTab,'Children');
            set(children(isprop(children,'Enable')),'Enable','on');


            RefVectorMatrix=zeros(189,3,189);

            for i=1:189
                k=app.RefCoordHead(i,:);
                RefVectorMatrix(:,:,i)=k-app.RefCoordHead;
            end

            REFMC=mat2cell(RefVectorMatrix,189,3,ones(189,1));
            app.RefVectorCellArray=cellfun(@(x) x(all(x,2),:),...
                REFMC,'UniformOutput',false);
            app.RefVectorCellArray=squeeze(app.RefVectorCellArray);


            %%% Load Data %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.UIFigure.Visible="off";
            commandwindow
            sp=uigetdir;
            if ischar(sp)
                app.selpath=sp;
                cd(app.selpath);

                app.pd=dir('*sdcurated.mat');
                app.pdg=dir('G*.tif');
                app.pdb=dir('B*.tif');
                app.nF=numel(app.pd);
                % if app.nF>1
                %     app.FrameSpinner.Limits=[1 app.nF];
                % else
                %     app.FrameSpinner.Enable='off';
                % end


                % app.Gstack=zeros(sizeI(1),sizeI(2),app.nF,'like',I);
                % app.cellStack=cell(app.nF,1);

                %%% Manually input voxel physical size

                [vx, vz]=xzprompt();
                app.scale=[vx vz];

                %%% Only consider case of 1 timeframe with 3 channels %%%%%%%%%

                if (numel(app.pd) ==2) && (numel(app.pdg)==1)...
                        && (numel(app.pdb)==1)

                    ISG=bfopen(app.pdg(1).name);
                    IG=cat(3,ISG{1,1}{:,1});
                    app.cellStack=cell(5,1);

                    app.nZ=size(IG,3);
                    app.zsliceSpinner.Value=round(app.nZ/2);
                    app.zsliceSpinner.Limits=[1 app.nZ];

                    ISB=bfopen(app.pdb(1).name);
                    IB=cat(3,ISB{1,1}{:,1});

                    %%% load, combine and clean Labels from R and G
                    %%% channels

                    LAll=cell(2,1);
                    for i=1:2
                        SL=load(app.pd(i).name);
                        fn=fieldnames(SL);
                        LAll{i}=SL.(fn{1});
                    end
                    LG=LAll{1};
                    LR=LAll{2};

                    Lcomb=SANcombineLabels(LG, LR);
                    L=SANremoveDupNeuronsLabel(Lcomb,app.scale(1),3);

                    [app.dopid, app.ndopid]=SANgetDopCentroid(L,IB);
                    app.nRefdopid=1:189;
                    app.nRefdopid(app.Refdopid)=[];

                    %%% create 4D stack with green and blue channels for
                    %%% display in app.UIAxes_MI %%%%%%%%%%%

                    app.GBStack=cat(4,IG,IB);
                    app.maxI(1)=max(IG(:));
                    app.maxI(2)=max(IB(:));


                    for i=1:5 %% replicate frame for pseudo timelapse
                        app.cellStack{i}=IG;

                    end

                    %%% metadata appear not to be transferred when saving single channels separately  %%%

                    % omeMeta=IS{1,4};
                    % voxX = omeMeta.getPixelsPhysicalSizeX(0);
                    % voxZ = omeMeta.getPixelsPhysicalSizeZ(0);
                    % app.scale(1)=double(voxX.value());
                    % app.scale(2)=double(voxZ.value());

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    app.UIFigure.Visible='on';
                    app.qim(1)=app.maxI(1);
                    app.qim(2)=app.maxI(2);
                    app.qimi(1)=0;
                    app.qimi(2)=0;
                    app.himage=imshow(app.GBStack(:,:,round(app.nZ/2),1),[app.qimi(1) app.qim(1)],...
                        'Parent',app.UIAxes_MP);
                    app.ftext=text(app.UIAxes_MP,50,50,'1','Color','y');
                    drawnow;
                    app.Label_3.Text='Computing Volume...';drawnow;
                    app.Label_4.Text='Computing Point Cloud...';drawnow;

                    [app.FrameLabels, app.prepData, app.rmse, ncflag] = ...
                        SANprepData(L);
                    if ncflag
                        uialert(app.UIFigure,...
                            ['Not enough neurons detected (only ' int2str(ncflag) ')'],...
                            'bad quality dataset');

                        children = get(app.REGISTRATIONTab,'Children');
                        set(children(isprop(children,'Enable')),'Enable','off');
                        app.Button.Enable='on';
                        app.IMPORTButton.Enable='on';
                        return
                    end

                    app.gCaMPData=SANPostStarDistAPP(app.cellStack,...
                        app.FrameLabels,app.prepData);
                    cen=cellfun(@(x) x.OrigCentroid,app.gCaMPData,...
                        'UniformOutput',false);
                    app.nFramesNeurons=cellfun(@(x) size(x,1),cen);
                    mcen=cellfun(@(x) mean(x,1,'omitnan'),cen,'UniformOutput',false);
                    app.nN=size(mcen,1);
                    vmcen=vertcat(mcen{:});
                    vmcen(:,[1 2])=vmcen(:,[1 2])*app.scale(1);
                    vmcen(:,3)=vmcen(:,3)*app.scale(2);
                    app.pcMoving=pointCloud(vmcen);
                    app.Label_3.Visible='off';

                    app.lvol=volshow(app.FrameLabels{1}>0,...
                        'Parent',app.VolumeRenderingPanel);
                    app.lvol.Transformation.A(3,3)=app.scale(2)/app.scale(1);
                    app.view3d=app.lvol.Parent;
                    % app.ZoomSlider.Value=app.view3d.CameraZoom;

                    %%% creating point clouds and initializing registration

                    app.pcRef=pointCloud(app.RefCoordHead);
                    [~, app.pcReg,~]=pcregistercpd(app.pcMoving,app.pcRef);
                    app.pcRegReset=app.pcReg;
                    app.Label_4.Visible='off';
                    app.refcl=app.pcRef.Location;
                    regcl=app.pcReg.Location;
                    app.href=scatter3(app.refcl(:,1),app.refcl(:,2),app.refcl(:,3),...
                        72,'g','filled','Parent',app.UIAxes);
                    app.href.MarkerFaceAlpha='flat';
                    app.href.AlphaDataMapping='none';
                    app.href.AlphaData=0.2*ones(189,1);
                    app.href.AlphaData(app.Refdopid)=1;

                    app.UIAxes.XLabel.String='X  (AP)';
                    app.UIAxes.YLabel.String='Y (LR)';
                    app.UIAxes.ZLabel.String='Z  (DV)';

                    axis(app.UIAxes,'equal');hold(app.UIAxes,'on');
                    app.hreg=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),72,...
                        'm','filled','Parent',app.UIAxes);
                    app.hreg.MarkerFaceAlpha='flat';
                    app.hreg.AlphaDataMapping='none';
                    app.hreg.AlphaData=0.2*ones(numel(app.hreg.XData),1);
                    app.hreg.AlphaData(app.dopid)=1;

                    app.UIAxes.Color=[0.2 0.2 0.2];
                    app.UIAxes.GridColor=[1 1 1];
                    grid(app.UIAxes,'on')


                    [~,dist]=knnsearch(app.refcl,regcl);
                    app.rsme1=mean(dist);
                    app.RMSETextArea.Value=num2str(app.rsme1,4);

                else

                    warning;
                end

            else
                app.UIFigure.Visible="on";


            end

        end

        % Value changed function: ChannelSpinner
        function ChannelSpinnerValueChanged(app, event)
            value = app.ChannelSpinner.Value;
            app.himage.CData=app.GBStack(:,:,app.zsliceSpinner.Value,value);
            app.UIAxes_MP.CLim=[app.qimi(value) app.qim(value)];
        end

        % Value changed function: zsliceSpinner
        function zsliceSpinnerValueChanged(app, event)
            value = app.zsliceSpinner.Value;
            app.himage.CData=app.GBStack(:,:,value,app.ChannelSpinner.Value);
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            value = app.ContrastSlider.Value;
            minv=value(1);
            maxv=value(2);
            app.qim(1)=app.maxI(1)*maxv;
            app.qim(2)=app.maxI(2)*maxv;
            app.qimi(1)=app.maxI(1)*minv;
            app.qimi(2)=app.maxI(2)*minv;
            app.UIAxes_MP.CLim=[app.qimi(app.ChannelSpinner.Value) app.qim(app.ChannelSpinner.Value)];
            
        end

        % Value changed function: XSpinner_TX
        function XSpinner_TXValueChanged(app, event)
            value = app.XSpinner_TX.Value;
            atf=affinetform3d;
            atf.A(1,4)=value;
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atf);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.XSpinner_TX.Value=0;

        end

        % Value changed function: YSpinner_TX
        function YSpinner_TXValueChanged(app, event)
            value = app.YSpinner_TX.Value;
            atf=affinetform3d;
            atf.A(2,4)=value;
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atf);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.YSpinner_TX.Value=0;
        end

        % Value changed function: ZSpinner_TX
        function ZSpinner_TXValueChanged(app, event)
            value = app.ZSpinner_TX.Value;
            atf=affinetform3d;
            atf.A(3,4)=value;
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atf);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.ZSpinner_TX.Value=0;
        end

        % Value changed function: XSpinner_Rot
        function XSpinner_RotValueChanged(app, event)
            value = app.XSpinner_Rot.Value;
            if value==90
                value=89;
            end
            atf=affinetform3d;
            atf.A(2,2)=cosd(value);
            atf.A(3,2)=sind(value);
            atf.A(2,3)=-sind(value);
            atf.A(3,3)=cosd(value);
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atf);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.XSpinner_Rot.Value=0;
        end

        % Value changed function: YSpinner_Rot
        function YSpinner_RotValueChanged(app, event)
            value = app.YSpinner_Rot.Value;
            if value==90
                value=89;
            end
            atf=affinetform3d;
            atf.A(1,1)=cosd(value);
            atf.A(1,3)=sind(value);
            atf.A(3,1)=-sind(value);
            atf.A(3,3)=cosd(value);
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atf);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.YSpinner_Rot.Value=0;
        end

        % Value changed function: ZSpinner_Rot
        function ZSpinner_RotValueChanged(app, event)
            value = app.ZSpinner_Rot.Value;
            if value==90
                value=89;
            elseif value==-90
                value=-89;
            end
            atf=affinetform3d;
            atf.A(1,1)=cosd(value);
            atf.A(2,1)=sind(value);
            atf.A(1,2)=-sind(value);
            atf.A(2,2)=cosd(value);
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atf);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.ZSpinner_Rot.Value=0;
        end

        % Value changed function: xaxisSwitch
        function xaxisSwitchValueChanged(app, event)
            %value = app.xaxisSwitch.Value;
            atfref=affinetform3d;
            atfref.A(1,1)=-atfref.A(1,1);
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atfref);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
        end

        % Value changed function: yaxisSwitch
        function yaxisSwitchValueChanged(app, event)
            %value = app.yaxisSwitch.Value;
            atfref=affinetform3d;
            atfref.A(2,2)=-atfref.A(2,2);
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atfref);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
        end

        % Value changed function: zaxisSwitch
        function zaxisSwitchValueChanged(app, event)
            %value = app.zaxisSwitch.Value;
            atfref=affinetform3d;
            atfref.A(3,3)=-atfref.A(3,3);
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atfref);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
        end

        % Value changed function: XSpinner_Sca
        function Spinner_ScaleValueChanged(app, event)
            value = app.XSpinner_Sca.Value;
            atfsca=affinetform3d;
            if app.syncaxesCheckBox.Value
                atfsca.A(1,1)=value;
                atfsca.A(2,2)=value;
                atfsca.A(3,3)=value;
            else
                atfsca.A(1,1)=value;
            end
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atfsca);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.XSpinner_Sca.Value=1;
        end

        % Value changed function: YSpinner_Sca
        function YSpinner_ScaValueChanged(app, event)
            value = app.YSpinner_Sca.Value;
            atfsca=affinetform3d;
            if app.syncaxesCheckBox.Value
                atfsca.A(1,1)=value;
                atfsca.A(2,2)=value;
                atfsca.A(3,3)=value;
            else
                atfsca.A(2,2)=value;
            end
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atfsca);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.YSpinner_Sca.Value=1;
        end

        % Value changed function: ZSpinner_Sca
        function ZSpinner_ScaValueChanged(app, event)
            value = app.ZSpinner_Sca.Value;
            atfsca=affinetform3d;
            if app.syncaxesCheckBox.Value
                atfsca.A(1,1)=value;
                atfsca.A(2,2)=value;
                atfsca.A(3,3)=value;
            else
                atfsca.A(3,3)=value;
            end
            Regcur=app.pcReg;
            Regcur=pctransform(Regcur,atfsca);
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            regcl=Regcur.Location;
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);
            app.pcReg=pointCloud(regcl);
            app.ZSpinner_Sca.Value=1;
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.pcReg=app.pcRegReset;
            Regcur=app.pcReg;
            regcl=Regcur.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
        end

        % Button pushed function: LOADNEURONSButton
        function LOADNEURONSButtonPushed(app, event)
            %%% Initialize lists  and graphs %%%%%%%%%%%%%%
            cla(app.UIAxes_MI);
            cla(app.UIAxes_Location);
            cla(app.UIAxes_GCaMP);
            app.ListBox_Filtered.Items={};
            app.ListBox_Filtered.ItemsData=[];
            app.ListBox_Selected.Items={};
            app.ListBox_Selected.ItemsData=[];
            app.UITable.Data=[];
            app.AssignNeurons=cell(app.nN,3); % cell array to store data for export
            app.neuroP=[];

            %%% Exp neurons vector calculations %%%%%%%%%%%%
            AntNRef=app.RefCoordHead(77,:); % most anterior ref neuron

            ExpHead=app.pcReg.Location;
            [~, dant]=knnsearch(AntNRef,ExpHead,"K",1);
            if dant>10
                ExpHead=[AntNRef;ExpHead];
                nNadj=app.nN+1;
            else
                nNadj=app.nN;
            end
            EXPM=zeros(nNadj,3,nNadj);
            for i=1:nNadj
                k=ExpHead(i,:);
                EXPM(:,:,i)=k-ExpHead;
            end
            EXPMC=mat2cell(EXPM,nNadj,3,ones(nNadj,1));
            app.ExpVectorCellArray=cellfun(@(x) x(all(x,2),:),...
                EXPMC,'UniformOutput',false);
            app.ExpVectorCellArray=squeeze(app.ExpVectorCellArray);
            if dant>10
            app.ExpVectorCellArray(1)=[];
            end

            %%% Load exp neurons data %%%%%%%%%%%%%%%%%%%%%%
            for i=1:app.nN
                T=app.gCaMPData{i};
                %T(:,[1 2])=[];
                app.neuronS(i).NeuronProperties=T;

                %%%% measure proba - to rework it - %%%%%
                D=pdist2(T.Centroid(1,:),T.Centroid); % what distance is it?
                m=max(D);
                s=std(D);
                DLim=m+s+5;
                Cur=app.pcReg.Location(i,:);
                [idx, dclose]=knnsearch(app.pcRef.Location,Cur,'K',50);
                tf=dclose>DLim;
                dclose(tf)=[];idx(tf)=[];
                ddclose=sum(dclose)-dclose;
                stats=ddclose/sum(ddclose);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                IDNx=app.IDN(idx);
                refheadx=app.RefCoordHead(idx,:);
                app.neuronS(i).NeuronID=table(IDNx,dclose',stats',refheadx,...
                    'VariableNames',...
                    {'ID','Distance','Probability','Centroid'});

            end

            %%% Compute matchpairs %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            regl=app.pcReg.Location;
            refl=app.pcRef.Location;
            Dall=pdist2(regl,refl);
            regldop=regl(app.dopid,:);
            refldop=refl(app.Refdopid,:);
            Ddop=pdist2(regldop,refldop);
            cost=max(min(Ddop,[],2));
            app.MPairs=matchpairs(Dall,cost);


            %%%  Initialize  plots and data %%%%%%%%%%%%%%%%%%%%%%%
            app.UIAxes_MI.XLabel.String='Neuron ID';
            app.UIAxes_MI.YLabel.String='Mean gCaMP intensity';

            app.himca=imshow(app.GBStack(:,:,app.zsliceSpinner.Value,app.ChannelSpinner.Value),...
                'Parent',app.UIAxes_GCaMP);
            app.zsliceSpinner_2.Value=app.zsliceSpinner.Value;
            app.ChannelSpinner_2.Value=app.ChannelSpinner.Value;
            colormap(app.UIAxes_GCaMP,'hot');
            clim(app.UIAxes_GCaMP,[app.qimi(app.ChannelSpinner_2.Value) app.qim(app.ChannelSpinner_2.Value)]);
            axis(app.UIAxes_GCaMP,'equal');
            hold(app.UIAxes_GCaMP,'on');
            app.LOADNEURONSButton.Enable="off";
            app.NP={app.neuronS.NeuronProperties}';
            app.NP=cellfun(@(x) x(1,:),app.NP,UniformOutput=false);
            app.NID={app.neuronS.NeuronID}';
            app.NPS=vertcat(app.NP{:,1});
            app.sneuronMI=cellfun(@(x)  x.MeanIntensity,app.NP,...
                'UniformOutput',false);
            app.sneuronNorm=cellfun(@(x) x.NormIntensity,app.NP,...
                'UniformOutput',false);
            app.mami=max(app.NPS.MeanIntensity);
            app.smami=cellfun(@max,app.sneuronMI);
            app.smanorm=cellfun(@max,app.sneuronNorm);
            app.mimi=min(app.NPS.MeanIntensity);
            app.avmi=mean(app.NPS.MeanIntensity);


            nfind=1:app.nN;
            nfind=nfind';
            enumN=int2str(nfind);
            app.elist=cellstr([repmat('Neuron#',app.nN,1) enumN]);
            app.ListBox_Filtered.Items=app.elist;
            s=uistyle("BackgroundColor",'c');
            addStyle(app.ListBox_Filtered,s,'item',app.dopid);


            app.ListBox_Filtered.ItemsData=nfind;

            app.UIAxes_MI.YLim=[app.mimi-10 app.mami+10];
            hold(app.UIAxes_MI,'on');
            app.hstem=stem(app.NPS.MeanIntensity,'filled','-k',Parent=app.UIAxes_MI);
            plot([1 app.nN],[app.avmi app.avmi],...
                '-c','Linewidth',2,'Parent',app.UIAxes_MI);


        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab==app.REGISTRATIONTab
                app.LOADNEURONSButton.Enable="on";
            end
        end

        % Button pushed function: XYButton
        function XYButtonPushed(app, event)
            app.UIAxes.View=[0,90];
        end

        % Button pushed function: XZButton
        function XZButtonPushed(app, event)
            app.UIAxes.View=[0, 0];
        end

        % Button pushed function: YZButton
        function YZButtonPushed(app, event)
            app.UIAxes.View=[90, 0];
        end

        % Button pushed function: HIDEButton
        function HIDEButtonPushed(app, event)


            if app.pcState==true
                app.pcState=false;
                app.HIDEButton.Text='SHOW';
                app.hreg.AlphaData(app.ndopid)=0;

                app.href.AlphaData(app.nRefdopid)=0;
            elseif app.pcState==false
                app.pcState=true;
                app.HIDEButton.Text='HIDE';
                app.hreg.AlphaData(app.ndopid)=0.2;
                app.href.AlphaData(app.nRefdopid)=0.2;
            end

        end

        % Callback function
        function PLAYButtonPushed(app, event)
            if ~isempty(app.neuroP)
                for i=1:app.nF
                    delete(findobj(app.UIAxes_GCaMP.Children,'Type','Scatter'));
                    app.himca.CData=app.Gstack(:,:,i);
                    k=find(app.neuroP.FrameID==i);
                    if ~isempty(k)
                        scatter(app.neuroP.OrigCentroid(k,1),app.neuroP.OrigCentroid(k,2),...
                            'og','LineWidth',1,'Parent',app.UIAxes_GCaMP);
                    end
                    pause(0.1);
                end
            end
        end

        % Double-clicked callback: ListBox_Filtered
        function ListBox_FilteredDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            %addidx=app.nfind(item);
            additem={['Neuron#' int2str(item)]};
            app.ListBox_Selected.Items=...
                [app.ListBox_Selected.Items, additem];
            app.ListBox_Selected.ItemsData=...
                [app.ListBox_Selected.ItemsData, item];
            app.neuroP=app.NP{item};
            % FrameID=fillVector(app.neuroP.FrameID);
            % nfrid=numel(FrameID);
            % MeanIntensity=nan(size(FrameID));
            % countfrid=1;
            % for i=1:nfrid
            %     if ~isnan(FrameID(i))
            %         MeanIntensity(i)=app.neuroP.MeanIntensity(countfrid);
            %         countfrid=countfrid+1;
            %     end
            % end

            app.AssignNeurons{item,1}=[app.neuroP.FrameID, app.neuroP.MeanIntensity];
            app.AssignNeurons{item,2}=app.NID{item};
        end

        % Clicked callback: ListBox_Filtered
        function ListBox_FilteredClicked(app, event)
            item = event.InteractionInformation.Item;
            app.neurovalue=app.ListBox_Filtered.ItemsData(item);
            ho=findobj(app.UIAxes_MI,'Type','stem');
            if numel(ho)==2
                delete(ho(1));
            end

            stem(app.neurovalue,app.NPS.MeanIntensity(app.neurovalue),'filled','m',Parent=app.UIAxes_MI);
            %app.AssignNeurons{neurovalue,1}=[app.neuroP.FrameID, app.neuroP.MeanIntensity];

            %%% switch analysis depending on algo %%%%%%%%%%%%

            switch app.IdentificationalgorithmSwitch.Value
                case '0'
                    app.UITable.Data=[];
                    IDSelect=app.NID{app.neurovalue};
                    app.UITable.Data=IDSelect(:,1:3);

                    cla(app.UIAxes_Location);
                    Cent=app.pcReg.Location(app.neurovalue,:);
                    CentRef=IDSelect.Centroid;
                    regcl=app.pcReg;
                    regcl=regcl.Location;
                    app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                        app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.2,...
                        'Parent',app.UIAxes_Location);
                    axis(app.UIAxes_Location,'equal');
                    hold(app.UIAxes_Location,'on');
                    app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                        'm','filled','MarkerFaceAlpha',0.2,...
                        'Parent',app.UIAxes_Location);
                    app.UIAxes_Location.Color=[0.2 0.2 0.2];
                    app.UIAxes_Location.GridColor=[1 1 1];
                    grid(app.UIAxes_Location,'on')
                    app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                        'Parent',app.UIAxes_Location);
                    app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                        72,'g','filled','Parent',app.UIAxes_Location);
                    text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                        CentRef(:,3),IDSelect.ID,'Color','w');
                    app.UIAxes_Location.XLim=[Cent(1)-25 Cent(1)+25];
                    app.UIAxes_Location.YLim=[Cent(2)-10 Cent(2)+10];
                    app.UIAxes_Location.ZLim=[Cent(3)-10 Cent(3)+10];
                    %app.AssignNeurons{neurovalue,2}=IDSelect;

                case '1'
                    app.UITable.Data=[];
                    if ~isempty(app.AssignNeurons{app.neurovalue,3})
                        app.UITable.Data=app.AssignNeurons{app.neurovalue,3}(1,1:3);
                        CentRef=app.AssignNeurons{app.neurovalue,3}.Centroid;
                        assneuronID=app.AssignNeurons{app.neurovalue,3}.ID;
                        cla(app.UIAxes_Location);
                        Cent=app.pcReg.Location(app.neurovalue,:);
                        regcl=app.pcReg;
                        regcl=regcl.Location;
                        app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                            app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.5,...
                            'Parent',app.UIAxes_Location);
                        axis(app.UIAxes_Location,'equal');
                        hold(app.UIAxes_Location,'on');
                        app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                            'm','filled','MarkerFaceAlpha',0.5,...
                            'Parent',app.UIAxes_Location);
                        app.UIAxes_Location.Color=[0.2 0.2 0.2];
                        app.UIAxes_Location.GridColor=[1 1 1];
                        grid(app.UIAxes_Location,'on')
                        app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                            'Parent',app.UIAxes_Location);
                        app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                            72,'g','filled','Parent',app.UIAxes_Location);
                        text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                            CentRef(:,3),assneuronID,'Color','w');
                        app.UIAxes_Location.XLim=[min([Cent(1) CentRef(1)])-25,...
                            max([Cent(1) CentRef(1)])+25];
                        app.UIAxes_Location.YLim=[min([Cent(2) CentRef(2)])-10,...
                            max([Cent(2) CentRef(2)])+10];
                        app.UIAxes_Location.ZLim=[min([Cent(3) CentRef(3)])-10,...
                            max([Cent(3) CentRef(3)])+10];
                    else
                        CurExpVector=app.ExpVectorCellArray{app.neurovalue};
                        oldpointer=app.UIFigure.Pointer;
                        app.UIFigure.Pointer='Watch';drawnow;
                        data=computeassignment(CurExpVector,...
                            app.RefVectorCellArray);
                        app.UIFigure.Pointer=oldpointer;drawnow;
                        [~, midx]=min(data(:,2));
                        CentRef=app.refcl(data(midx,1),:);
                        cla(app.UIAxes_Location);
                        Cent=app.pcReg.Location(app.neurovalue,:);
                        regcl=app.pcReg;
                        regcl=regcl.Location;
                        app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                            app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.2,...
                            'Parent',app.UIAxes_Location);
                        axis(app.UIAxes_Location,'equal');
                        hold(app.UIAxes_Location,'on');
                        app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                            'm','filled','MarkerFaceAlpha',0.2,...
                            'Parent',app.UIAxes_Location);
                        app.UIAxes_Location.Color=[0.2 0.2 0.2];
                        app.UIAxes_Location.GridColor=[1 1 1];
                        grid(app.UIAxes_Location,'on')
                        app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                            'Parent',app.UIAxes_Location);
                        app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                            72,'g','filled','Parent',app.UIAxes_Location);
                        text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                            CentRef(:,3),app.IDN(data(midx,1)),'Color','w');
                        app.UIAxes_Location.XLim=[min([Cent(1) CentRef(1)])-25,...
                            max([Cent(1) CentRef(1)])+25];
                        app.UIAxes_Location.YLim=[min([Cent(2) CentRef(2)])-10,...
                            max([Cent(2) CentRef(2)])+10];
                        app.UIAxes_Location.ZLim=[min([Cent(3) CentRef(3)])-10,...
                            max([Cent(3) CentRef(3)])+10];
                        distm=[Cent;CentRef];
                        ddistm=pdist(distm);
                        IDSelect=table(app.IDN(data(midx,1)),ddistm,NaN,CentRef,...
                            'VariableNames',{'ID','Distance','Probability','Centroid'});
                        app.UITable.Data=IDSelect(1,1:3);
                        %app.AssignNeurons{neurovalue,3}=IDSelect;
                    end

            end
            centro=app.pcMoving.Location;
            centrocur=centro(app.neurovalue,:);
            zslice=round(centrocur(3)/app.scale(2));
            app.zsliceSpinner_2.Value=zslice;
            app.ChannelSpinner_2.Value=1;
            app.himca.CData=app.GBStack(:,:,zslice,1);
            app.UIAxes_GCaMP.CLim=[app.qimi(1) app.qim(1)];
            hoh=findobj(app.UIAxes_GCaMP,'Type','scatter');
            if ~isempty(hoh)
                delete(hoh);
            end
            scatter(centrocur(1)/app.scale(1),centrocur(2)/app.scale(1),'oc',Parent=app.UIAxes_GCaMP);
        end

        % Clicked callback: ListBox_Selected
        function ListBox_SelectedClicked(app, event)
            item = event.InteractionInformation.Item;
            app.neurovalue=app.ListBox_Selected.ItemsData(item);

            cla(app.UIAxes_MI);
            app.UIAxes_MI.YLim=[app.mimi-10 app.mami+10];
            plot([1 app.nF],[app.avmi app.avmi],...
                '-k','Parent',app.UIAxes_MI);
            hold(app.UIAxes_MI,'on');
            app.neuroP=app.NP{app.neurovalue};
            FrameID=fillVector(app.neuroP.FrameID);
            nfrid=numel(FrameID);
            MeanIntensity=nan(size(FrameID));
            countfrid=1;
            for i=1:nfrid
                if ~isnan(FrameID(i))
                    MeanIntensity(i)=app.neuroP.MeanIntensity(countfrid);
                    countfrid=countfrid+1;
                end
            end
            plot(FrameID,MeanIntensity,...
                '-c','Linewidth',2,'Parent',app.UIAxes_MI)
            %app.AssignNeurons{app.neurovalue,1}=[app.neuroP.FrameID, app.neuroP.MeanIntensity];
            %%% switch analysis depending on algo %%%%%%%%%%%%

            switch app.IdentificationalgorithmSwitch.Value
                case '0'
                    app.UITable.Data=[];
                    IDSelect=app.NID{app.neurovalue};
                    app.UITable.Data=IDSelect(:,1:3);

                    cla(app.UIAxes_Location);
                    Cent=app.pcReg.Location(app.neurovalue,:);
                    CentRef=IDSelect.Centroid;
                    regcl=app.pcReg;
                    regcl=regcl.Location;
                    app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                        app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.2,...
                        'Parent',app.UIAxes_Location);
                    axis(app.UIAxes_Location,'equal');
                    hold(app.UIAxes_Location,'on');
                    app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                        'm','filled','MarkerFaceAlpha',0.2,...
                        'Parent',app.UIAxes_Location);
                    app.UIAxes_Location.Color=[0.2 0.2 0.2];
                    app.UIAxes_Location.GridColor=[1 1 1];
                    grid(app.UIAxes_Location,'on')
                    app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                        'Parent',app.UIAxes_Location);
                    app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                        72,'g','filled','Parent',app.UIAxes_Location);
                    text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                        CentRef(:,3),IDSelect.ID,'Color','w');
                    app.UIAxes_Location.XLim=[Cent(1)-25 Cent(1)+25];
                    app.UIAxes_Location.YLim=[Cent(2)-10 Cent(2)+10];
                    app.UIAxes_Location.ZLim=[Cent(3)-10 Cent(3)+10];

                case '1'
                    app.UITable.Data=[];
                    if ~isempty(app.AssignNeurons{app.neurovalue,3})
                        app.UITable.Data=app.AssignNeurons{app.neurovalue,3}(1,1:3);
                        CentRef=app.AssignNeurons{app.neurovalue,3}.Centroid;
                        assneuronID=app.AssignNeurons{app.neurovalue,3}.ID;
                        cla(app.UIAxes_Location);
                        Cent=app.pcReg.Location(app.neurovalue,:);
                        regcl=app.pcReg;
                        regcl=regcl.Location;
                        app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                            app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.5,...
                            'Parent',app.UIAxes_Location);
                        axis(app.UIAxes_Location,'equal');
                        hold(app.UIAxes_Location,'on');
                        app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                            'm','filled','MarkerFaceAlpha',0.5,...
                            'Parent',app.UIAxes_Location);
                        app.UIAxes_Location.Color=[0.2 0.2 0.2];
                        app.UIAxes_Location.GridColor=[1 1 1];
                        grid(app.UIAxes_Location,'on')
                        app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                            'Parent',app.UIAxes_Location);
                        app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                            72,'g','filled','Parent',app.UIAxes_Location);
                        text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                            CentRef(:,3),assneuronID,'Color','w');
                        app.UIAxes_Location.XLim=[min([Cent(1) CentRef(1)])-25,...
                            max([Cent(1) CentRef(1)])+25];
                        app.UIAxes_Location.YLim=[min([Cent(2) CentRef(2)])-10,...
                            max([Cent(2) CentRef(2)])+10];
                        app.UIAxes_Location.ZLim=[min([Cent(3) CentRef(3)])-10,...
                            max([Cent(3) CentRef(3)])+10];
                    else
                        CurExpVector=app.ExpVectorCellArray{app.neurovalue};
                        oldpointer=app.UIFigure.Pointer;
                        app.UIFigure.Pointer='Watch';drawnow;
                        data=computeassignment(CurExpVector,...
                            app.RefVectorCellArray);
                        app.UIFigure.Pointer=oldpointer;drawnow;
                        [~, midx]=min(data(:,2));
                        CentRef=app.refcl(data(midx,1),:);
                        cla(app.UIAxes_Location);
                        Cent=app.pcReg.Location(app.neurovalue,:);
                        regcl=app.pcReg.Location;
                        app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                            app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.5,...
                            'Parent',app.UIAxes_Location);
                        axis(app.UIAxes_Location,'equal');
                        hold(app.UIAxes_Location,'on');
                        app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                            'm','filled','MarkerFaceAlpha',0.5,...
                            'Parent',app.UIAxes_Location);
                        app.UIAxes_Location.Color=[0.2 0.2 0.2];
                        app.UIAxes_Location.GridColor=[1 1 1];
                        grid(app.UIAxes_Location,'on')
                        app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                            'Parent',app.UIAxes_Location);
                        app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                            72,'g','filled','Parent',app.UIAxes_Location);
                        text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                            CentRef(:,3),app.IDN(data(midx,1)),'Color','w');
                        app.UIAxes_Location.XLim=[min([Cent(1) CentRef(1)])-25,...
                            max([Cent(1) CentRef(1)])+25];
                        app.UIAxes_Location.YLim=[min([Cent(2) CentRef(2)])-10,...
                            max([Cent(2) CentRef(2)])+10];
                        app.UIAxes_Location.ZLim=[min([Cent(3) CentRef(3)])-10,...
                            max([Cent(3) CentRef(3)])+10];
                        distm=[Cent;CentRef];
                        ddistm=pdist(distm);
                        IDSelect=table(app.IDN(data(midx,1)),ddistm,NaN,CentRef,...
                            'VariableNames',{'ID','Distance','Probability','Centroid'});
                        app.AssignNeurons{app.neurovalue,3}=IDSelect;
                        app.UITable.Data=IDSelect(1,1:3);
                    end

            end

        end

        % Double-clicked callback: ListBox_Selected
        function ListBox_SelectedDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            app.ListBox_Selected.Items(item)=[];
            app.ListBox_Selected.ItemsData(item)=[];
            app.AssignNeurons(app.ListBox_Selected.ItemsData(item),:)=[];
        end

        % Button pushed function: SNAPButton
        function SNAPButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            app.UIFigure.Visible='off';
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportapp(app.UIFigure,[filepath filename]);
            end
            app.UIFigure.Visible='on';

        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)

            tf=cellfun(@isempty,app.AssignNeurons);
            k=find(~tf(:,1));
            filter = {'*.xlsx'};
            app.UIFigure.Visible='off';
            [filename,filepath] = uiputfile(filter);
            app.UIFigure.Visible='on';
            oldpointer=app.UIFigure.Pointer;
            app.UIFigure.Pointer='Watch';drawnow;
            filenameID=[filename(1:end-5) 'ID.xlsx' ];
            if ischar(filename)
                for i=1:numel(k)

                    writetable(array2table(app.AssignNeurons{k(i),1},...
                        "VariableNames",{'Frame#','MeanIntensity'}),...
                        [filepath,filename],...
                        'sheet',['Neuron#' int2str(k(i))]);
                    if isempty(app.AssignNeurons{k(i),3})
                        data=computeassignment(app.ExpVectorCellArray{k(i)},...
                            app.RefVectorCellArray);
                        [~, midx]=min(data(:,2));
                        CentRef=app.refcl(data(midx,1),:);
                        Cent=app.pcReg.Location(k(i),:);
                        ddist=pdist([Cent;CentRef]);
                        app.AssignNeurons{k(i),3}=...
                            table(app.IDN(data(midx,1)),ddist,NaN,CentRef,...
                            'VariableNames',{'ID','Distance','Probability','Centroid'});
                    end
                    IDfast=app.AssignNeurons{k(i),2};
                    IDfast.Centroid=[];
                    %IDslow=array2table(app.AssignNeurons{k(i),3}(1,1:3),...
                    %'VariableNames',{'ID','Distance','Probability'});
                    IDslow=app.AssignNeurons{k(i),3};
                    IDslow.Centroid=[];
                    C=[IDfast;IDslow];
                    writetable(C,[filepath filenameID],'sheet',['Neuron#' int2str(k(i))]);
                end
                app.UIFigure.Pointer=oldpointer;

            end
        end

        % Value changed function: IdentificationalgorithmSwitch
        function IdentificationalgorithmSwitchValueChanged(app, event)
            value = app.IdentificationalgorithmSwitch.Value;
            if ~isempty(app.neurovalue)
                switch value
                    case '0'
                        app.UITable.Data=[];
                        IDSelect=app.NID{app.neurovalue};
                        app.UITable.Data=IDSelect(:,1:3);

                        cla(app.UIAxes_Location);
                        Cent=app.pcReg.Location(app.neurovalue,:);
                        CentRef=IDSelect.Centroid;
                        regcl=app.pcReg;
                        regcl=regcl.Location;
                        app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                            app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.2,...
                            'Parent',app.UIAxes_Location);
                        axis(app.UIAxes_Location,'equal');
                        hold(app.UIAxes_Location,'on');
                        app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                            'm','filled','MarkerFaceAlpha',0.2,...
                            'Parent',app.UIAxes_Location);
                        app.UIAxes_Location.Color=[0.2 0.2 0.2];
                        app.UIAxes_Location.GridColor=[1 1 1];
                        grid(app.UIAxes_Location,'on')
                        app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                            'Parent',app.UIAxes_Location);
                        app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                            72,'g','filled','Parent',app.UIAxes_Location);
                        text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                            CentRef(:,3),IDSelect.ID,'Color','w');
                        app.UIAxes_Location.XLim=[Cent(1)-25 Cent(1)+25];
                        app.UIAxes_Location.YLim=[Cent(2)-10 Cent(2)+10];
                        app.UIAxes_Location.ZLim=[Cent(3)-10 Cent(3)+10];

                    case '1'
                        app.UITable.Data=[];
                        if ~isempty(app.AssignNeurons{app.neurovalue,3})
                            app.UITable.Data=app.AssignNeurons{app.neurovalue,3}(1,1:3);
                            CentRef=app.AssignNeurons{app.neurovalue,3}.Centroid;
                            assneuronID=app.AssignNeurons{app.neurovalue,3}.ID;
                            cla(app.UIAxes_Location);
                            Cent=app.pcReg.Location(app.neurovalue,:);
                            regcl=app.pcReg;
                            regcl=regcl.Location;
                            app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                                app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.5,...
                                'Parent',app.UIAxes_Location);
                            axis(app.UIAxes_Location,'equal');
                            hold(app.UIAxes_Location,'on');
                            app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                                'm','filled','MarkerFaceAlpha',0.5,...
                                'Parent',app.UIAxes_Location);
                            app.UIAxes_Location.Color=[0.2 0.2 0.2];
                            app.UIAxes_Location.GridColor=[1 1 1];
                            grid(app.UIAxes_Location,'on')
                            app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                                'Parent',app.UIAxes_Location);
                            app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                                72,'g','filled','Parent',app.UIAxes_Location);
                            text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                                CentRef(:,3),assneuronID,'Color','w');
                            app.UIAxes_Location.XLim=[min([Cent(1) CentRef(1)])-25,...
                                max([Cent(1) CentRef(1)])+25];
                            app.UIAxes_Location.YLim=[min([Cent(2) CentRef(2)])-10,...
                                max([Cent(2) CentRef(2)])+10];
                            app.UIAxes_Location.ZLim=[min([Cent(3) CentRef(3)])-10,...
                                max([Cent(3) CentRef(3)])+10];
                        else
                            CurExpVector=app.ExpVectorCellArray{app.neurovalue};
                            oldpointer=app.UIFigure.Pointer;
                            app.UIFigure.Pointer='Watch';drawnow;
                            data=computeassignment(CurExpVector,...
                                app.RefVectorCellArray);
                            app.UIFigure.Pointer=oldpointer;drawnow;
                            [~, midx]=min(data(:,2));
                            CentRef=app.refcl(data(midx,1),:);
                            cla(app.UIAxes_Location);
                            Cent=app.pcReg.Location(app.neurovalue,:);
                            regcl=app.pcReg;
                            regcl=regcl.Location;
                            app.hrefloc=scatter3(app.refcl(:,1),app.refcl(:,2),...
                                app.refcl(:,3),'g','filled','MarkerFaceAlpha',0.5,...
                                'Parent',app.UIAxes_Location);
                            axis(app.UIAxes_Location,'equal');
                            hold(app.UIAxes_Location,'on');
                            app.hregloc=scatter3(regcl(:,1),regcl(:,2),regcl(:,3),...
                                'm','filled','MarkerFaceAlpha',0.5,...
                                'Parent',app.UIAxes_Location);
                            app.UIAxes_Location.Color=[0.2 0.2 0.2];
                            app.UIAxes_Location.GridColor=[1 1 1];
                            grid(app.UIAxes_Location,'on')
                            app.hreglocs=scatter3(Cent(1),Cent(2),Cent(3),72,'m','filled',...
                                'Parent',app.UIAxes_Location);
                            app.hreflocs=scatter3(CentRef(:,1),CentRef(:,2),CentRef(:,3),...
                                72,'g','filled','Parent',app.UIAxes_Location);
                            text(app.UIAxes_Location,CentRef(:,1),CentRef(:,2),...
                                CentRef(:,3),app.IDN(data(midx,1)),'Color','w');
                            app.UIAxes_Location.XLim=[min([Cent(1) CentRef(1)])-25,...
                                max([Cent(1) CentRef(1)])+25];
                            app.UIAxes_Location.YLim=[min([Cent(2) CentRef(2)])-10,...
                                max([Cent(2) CentRef(2)])+10];
                            app.UIAxes_Location.ZLim=[min([Cent(3) CentRef(3)])-10,...
                                max([Cent(3) CentRef(3)])+10];
                            distm=[Cent;CentRef];
                            ddistm=pdist(distm);
                            IDSelect=table(app.IDN(data(midx,1)),ddistm,NaN,CentRef,...
                                'VariableNames',{'ID','Distance','Probability','Centroid'});
                            app.AssignNeurons{app.neurovalue,3}=IDSelect;
                            app.UITable.Data=IDSelect(1,1:3);
                        end

                end
            end
        end

        % Button pushed function: AUTOREGISTERButton
        function AUTOREGISTERButtonPushed(app, event)
            [~, app.pcReg]=pcregistericp(app.pcReg,app.pcRef);
            regcl=app.pcReg.Location;
            app.hreg.XData=regcl(:,1);
            app.hreg.YData=regcl(:,2);
            app.hreg.ZData=regcl(:,3);
            [~,dist]=knnsearch(app.refcl,regcl);
            app.rsme1=mean(dist);
            app.RMSETextArea.Value=num2str(app.rsme1,4);

        end

        % Button pushed function: EXPORTALLButton
        function EXPORTALLButtonPushed(app, event)

            NPSS.MeanIntensity=app.NPS.MeanIntensity;
            NPSS.Centroid=app.NPS.Centroid;
            NPSS=struct2table(NPSS);
            Expv=app.ExpVectorCellArray;
            Refv=app.RefVectorCellArray;
            IDN=app.IDN;
            nearID=cell(app.nN,1);
            assID=cell(app.nN,1);
            mpID=cell(app.nN,1);
            trueID=cell(app.nN,1);
            allNID=num2cell([NaN NaN]);

            mpexp=app.MPairs(:,1);
            mpref=app.MPairs(:,2);
            NNID=app.NID;
            nnN=app.nN;
            nnnN=nnN;

            D = parallel.pool.DataQueue;
            fwb=waitbar(0,'Exporting data...');
            afterEach(D, @nUpdateWaitbar);
            p=1;


            parfor i=1:nnN
                nn=NNID{i};
                if ~isempty(nn)
                    nearID{i}=nn.ID(1);

                else
                    nearID{i}='none';

                end

                data=computeassignment(Expv{i},Refv);
                [~, midx]=min(data(:,2));
                assID{i}=IDN{data(midx,1)};

                k=mpexp==i;
                if any(k)
                    mpID{i}=IDN{mpref(k)};
                else
                    mpID{i}='none';
                end
                if ismember(assID{i},nn.ID)
                    trueID{i}='true';
                else
                    trueID{i}='false';
                end

                send(D, i);

            end

            for i=1:nnnN
                nn=NNID{i};
                if ~isempty(nn)
                    nidnid=nn.ID;
                    s=numel(nidnid);
                    n=NaN(s,1);
                    n(1)=i;
                    tNID=[num2cell(n) nidnid];
                else
                    tNID=[num2cell(i) num2cell(NaN)];
                end
                allNID=[allNID;tNID;num2cell([NaN NaN])];
            end

            allID=[nearID mpID assID trueID];
            allIDT=cell2table(allID,'VariableNames',{'nearestN','matchedPairs','Assigned','isInBoth'});
            fT=[NPSS allIDT];

            S=split(app.selpath,filesep);
            snameall=fullfile(app.selpath,[S{end} '.xlsx']);
            snameID=fullfile(app.selpath, [S{end} 'nearestID.xlsx']);

            writetable(fT,snameall);
            allNID=[{'Neuron#','nearestN'};allNID];
            writematrix(allNID,snameID);
            close(fwb);

            function nUpdateWaitbar(~)
                waitbar(p/nnN, fwb);
                p = p + 1;
            end


        end

        % Value changed function: zsliceSpinner_2
        function zsliceSpinner_2ValueChanged(app, event)
            value = app.zsliceSpinner_2.Value;
            app.himca.CData=app.GBStack(:,:,value,app.ChannelSpinner_2.Value);
            
        end

        % Value changed function: ChannelSpinner_2
        function ChannelSpinner_2ValueChanged(app, event)
            value = app.ChannelSpinner_2.Value;
            app.himca.CData=app.GBStack(:,:,app.zsliceSpinner_2.Value,value);
            app.UIAxes_GCaMP.CLim=[app.qimi(value) app.qim(value)];
            
        end

        % Value changed function: ContrastSlider_2
        function ContrastSlider_2ValueChanged(app, event)
            value = app.ContrastSlider_2.Value;
            minv=value(1);
            maxv=value(2);
            app.qim(1)=app.maxI(1)*maxv;
            app.qim(2)=app.maxI(2)*maxv;
            app.qimi(1)=app.maxI(1)*minv;
            app.qimi(2)=app.maxI(2)*minv;
            app.UIAxes_GCaMP.CLim=[app.qimi(app.ChannelSpinner_2.Value) app.qim(app.ChannelSpinner_2.Value)];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.REGISTRATIONTab = uitab(app.TabGroup);
            app.REGISTRATIONTab.Title = 'REGISTRATION';
            app.UIAxes_MP = uiaxes(app.REGISTRATIONTab);
            title(app.UIAxes_MP, 'gCaMP')
            app.UIAxes_MP.XTick = [];
            app.UIAxes_MP.YTick = [];
            app.UIAxes_MP.ZTick = [];
            app.UIAxes = uiaxes(app.REGISTRATIONTab);
            title(app.UIAxes, 'Registration')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RESETButton = uibutton(app.REGISTRATIONTab, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.AUTOREGISTERButton = uibutton(app.REGISTRATIONTab, 'push');
            app.AUTOREGISTERButton.ButtonPushedFcn = createCallbackFcn(app, @AUTOREGISTERButtonPushed, true);
            app.AUTOREGISTERButton.Text = 'AUTO REGISTER';
            app.TranslationPanel = uipanel(app.REGISTRATIONTab);
            app.TranslationPanel.Title = 'Translation';
            app.XSpinnerLabel = uilabel(app.TranslationPanel);
            app.XSpinnerLabel.Text = 'X-';
            app.XSpinner_TX = uispinner(app.TranslationPanel);
            app.XSpinner_TX.ValueChangedFcn = createCallbackFcn(app, @XSpinner_TXValueChanged, true);
            app.YSpinnerLabel = uilabel(app.TranslationPanel);
            app.YSpinnerLabel.Text = 'Y-';
            app.YSpinner_TX = uispinner(app.TranslationPanel);
            app.YSpinner_TX.ValueChangedFcn = createCallbackFcn(app, @YSpinner_TXValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.TranslationPanel);
            app.ZSpinnerLabel.Text = 'Z-';
            app.ZSpinner_TX = uispinner(app.TranslationPanel);
            app.ZSpinner_TX.ValueChangedFcn = createCallbackFcn(app, @ZSpinner_TXValueChanged, true);
            app.RotationPanel = uipanel(app.REGISTRATIONTab);
            app.RotationPanel.Title = 'Rotation';
            app.XSpinner_2Label = uilabel(app.RotationPanel);
            app.XSpinner_2Label.Text = 'X-';
            app.XSpinner_Rot = uispinner(app.RotationPanel);
            app.XSpinner_Rot.ValueChangedFcn = createCallbackFcn(app, @XSpinner_RotValueChanged, true);
            app.YSpinner_2Label = uilabel(app.RotationPanel);
            app.YSpinner_2Label.Text = 'Y-';
            app.YSpinner_Rot = uispinner(app.RotationPanel);
            app.YSpinner_Rot.ValueChangedFcn = createCallbackFcn(app, @YSpinner_RotValueChanged, true);
            app.ZSpinner_2Label = uilabel(app.RotationPanel);
            app.ZSpinner_2Label.Text = 'Z-';
            app.ZSpinner_Rot = uispinner(app.RotationPanel);
            app.ZSpinner_Rot.ValueChangedFcn = createCallbackFcn(app, @ZSpinner_RotValueChanged, true);
            app.ReflectionPanel = uipanel(app.REGISTRATIONTab);
            app.ReflectionPanel.Title = 'Reflection';
            app.xaxisSwitchLabel = uilabel(app.ReflectionPanel);
            app.xaxisSwitchLabel.Text = 'x-axis';
            app.xaxisSwitch = uiswitch(app.ReflectionPanel, 'slider');
            app.xaxisSwitch.ValueChangedFcn = createCallbackFcn(app, @xaxisSwitchValueChanged, true);
            app.yaxisSwitchLabel = uilabel(app.ReflectionPanel);
            app.yaxisSwitchLabel.Text = 'y-axis';
            app.yaxisSwitch = uiswitch(app.ReflectionPanel, 'slider');
            app.yaxisSwitch.ValueChangedFcn = createCallbackFcn(app, @yaxisSwitchValueChanged, true);
            app.zaxisSwitchLabel = uilabel(app.ReflectionPanel);
            app.zaxisSwitchLabel.Text = 'z-axis';
            app.zaxisSwitch = uiswitch(app.ReflectionPanel, 'slider');
            app.zaxisSwitch.ValueChangedFcn = createCallbackFcn(app, @zaxisSwitchValueChanged, true);
            app.ScalingPanel = uipanel(app.REGISTRATIONTab);
            app.ScalingPanel.Title = 'Scaling';
            app.XSpinnerLabel_2 = uilabel(app.ScalingPanel);
            app.XSpinnerLabel_2.Text = 'X-';
            app.XSpinner_Sca = uispinner(app.ScalingPanel);
            app.XSpinner_Sca.Step = 0.05;
            app.XSpinner_Sca.Limits = [0 Inf];
            app.XSpinner_Sca.ValueChangedFcn = createCallbackFcn(app, @Spinner_ScaleValueChanged, true);
            app.XSpinner_Sca.Value = 1;
            app.YLabel = uilabel(app.ScalingPanel);
            app.YLabel.Text = 'Y-';
            app.YSpinner_Sca = uispinner(app.ScalingPanel);
            app.YSpinner_Sca.Step = 0.05;
            app.YSpinner_Sca.Limits = [0 Inf];
            app.YSpinner_Sca.ValueChangedFcn = createCallbackFcn(app, @YSpinner_ScaValueChanged, true);
            app.YSpinner_Sca.Value = 1;
            app.ZLabel = uilabel(app.ScalingPanel);
            app.ZLabel.Text = 'Z-';
            app.ZSpinner_Sca = uispinner(app.ScalingPanel);
            app.ZSpinner_Sca.Step = 0.05;
            app.ZSpinner_Sca.Limits = [0 Inf];
            app.ZSpinner_Sca.ValueChangedFcn = createCallbackFcn(app, @ZSpinner_ScaValueChanged, true);
            app.ZSpinner_Sca.Value = 1;
            app.syncaxesCheckBox = uicheckbox(app.ScalingPanel);
            app.syncaxesCheckBox.Text = 'sync axes';
            app.RMSETextArea = uitextarea(app.REGISTRATIONTab);
            app.VolumeRenderingPanel = uipanel(app.REGISTRATIONTab);
            app.VolumeRenderingPanel.Title = 'Volume Rendering';
            app.Label_3 = uilabel(app.VolumeRenderingPanel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.REGISTRATIONTab);
            app.Label_4.Text = '';
            app.HIDEButton = uibutton(app.REGISTRATIONTab, 'push');
            app.HIDEButton.ButtonPushedFcn = createCallbackFcn(app, @HIDEButtonPushed, true);
            app.HIDEButton.Text = 'HIDE';
            app.XYButton = uibutton(app.REGISTRATIONTab, 'push');
            app.XYButton.ButtonPushedFcn = createCallbackFcn(app, @XYButtonPushed, true);
            app.XYButton.Text = 'XY';
            app.XZButton = uibutton(app.REGISTRATIONTab, 'push');
            app.XZButton.ButtonPushedFcn = createCallbackFcn(app, @XZButtonPushed, true);
            app.XZButton.Text = 'XZ';
            app.YZButton = uibutton(app.REGISTRATIONTab, 'push');
            app.YZButton.ButtonPushedFcn = createCallbackFcn(app, @YZButtonPushed, true);
            app.YZButton.Text = 'YZ';
            app.ContrastSliderLabel = uilabel(app.REGISTRATIONTab);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.REGISTRATIONTab, 'range');
            app.ContrastSlider.Limits = [0 1];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Value = [0 1];
            app.zsliceSpinner = uispinner(app.REGISTRATIONTab);
            app.zsliceSpinner.ValueChangedFcn = createCallbackFcn(app, @zsliceSpinnerValueChanged, true);
            app.ChannelSpinner = uispinner(app.REGISTRATIONTab);
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelSpinnerValueChanged, true);
            app.IMPORTButton = uibutton(app.REGISTRATIONTab, 'push');
            app.IMPORTButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTButtonPushed, true);
            app.IMPORTButton.Text = 'IMPORT';
            app.ChannelSpinnerLabel = uilabel(app.REGISTRATIONTab);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.zsliceSpinnerLabel = uilabel(app.REGISTRATIONTab);
            app.zsliceSpinnerLabel.Text = 'z-slice';
            app.ANALYSISTab = uitab(app.TabGroup);
            app.ANALYSISTab.Title = 'ANALYSIS';
            app.UIAxes_MI = uiaxes(app.ANALYSISTab);
            title(app.UIAxes_MI, 'Signal Intensity')
            xlabel(app.UIAxes_MI, 'X')
            ylabel(app.UIAxes_MI, 'Y')
            zlabel(app.UIAxes_MI, 'Z')
            app.UIAxes_Location = uiaxes(app.ANALYSISTab);
            title(app.UIAxes_Location, 'Location')
            xlabel(app.UIAxes_Location, 'X')
            ylabel(app.UIAxes_Location, 'Y')
            zlabel(app.UIAxes_Location, 'Z')
            app.UIAxes_Location.YDir = 'reverse';
            app.UIAxes_GCaMP = uiaxes(app.ANALYSISTab);
            title(app.UIAxes_GCaMP, 'gCaMP')
            xlabel(app.UIAxes_GCaMP, 'X')
            ylabel(app.UIAxes_GCaMP, 'Y')
            zlabel(app.UIAxes_GCaMP, 'Z')
            app.UITable = uitable(app.ANALYSISTab);
            app.UITable.ColumnName = {'Neuron ID'; 'Distance'; 'Probability'};
            app.UITable.RowName = {};
            app.EXPORTALLButton = uibutton(app.ANALYSISTab, 'push');
            app.EXPORTALLButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTALLButtonPushed, true);
            app.EXPORTALLButton.Text = 'EXPORT ALL';
            app.EXPORTButton = uibutton(app.ANALYSISTab, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.IdentificationalgorithmSwitchLabel = uilabel(app.ANALYSISTab);
            app.IdentificationalgorithmSwitchLabel.Text = 'Identification algorithm';
            app.SNAPButton = uibutton(app.ANALYSISTab, 'push');
            app.SNAPButton.ButtonPushedFcn = createCallbackFcn(app, @SNAPButtonPushed, true);
            app.SNAPButton.Text = 'SNAP';
            app.IdentificationalgorithmSwitch = uiswitch(app.ANALYSISTab, 'slider');
            app.IdentificationalgorithmSwitch.Items = {'Neighborhood (fast)', 'Assignment (slow)'};
            app.IdentificationalgorithmSwitch.ItemsData = {'0', '1'};
            app.IdentificationalgorithmSwitch.ValueChangedFcn = createCallbackFcn(app, @IdentificationalgorithmSwitchValueChanged, true);
            app.IdentificationalgorithmSwitch.Value = '0';
            app.ListBox_Selected = uilistbox(app.ANALYSISTab);
            app.ListBox_Selected.Items = {};
            app.ListBox_Selected.ClickedFcn = createCallbackFcn(app, @ListBox_SelectedClicked, true);
            app.ListBox_Selected.DoubleClickedFcn = createCallbackFcn(app, @ListBox_SelectedDoubleClicked, true);
            app.ListBox_Selected.Value = {};
            app.SelectedNeuronsLabel = uilabel(app.ANALYSISTab);
            app.SelectedNeuronsLabel.Text = 'Selected Neurons';
            app.ListBox_Filtered = uilistbox(app.ANALYSISTab);
            app.ListBox_Filtered.Items = {};
            app.ListBox_Filtered.ClickedFcn = createCallbackFcn(app, @ListBox_FilteredClicked, true);
            app.ListBox_Filtered.DoubleClickedFcn = createCallbackFcn(app, @ListBox_FilteredDoubleClicked, true);
            app.ListBox_Filtered.Value = {};
            app.FilteredNeuronsLabel = uilabel(app.ANALYSISTab);
            app.FilteredNeuronsLabel.Text = 'Filtered Neurons';
            app.LOADNEURONSButton = uibutton(app.ANALYSISTab, 'push');
            app.LOADNEURONSButton.ButtonPushedFcn = createCallbackFcn(app, @LOADNEURONSButtonPushed, true);
            app.LOADNEURONSButton.Text = 'LOAD NEURONS';
            app.ContrastSlider_2 = uislider(app.ANALYSISTab, 'range');
            app.ContrastSlider_2.Limits = [0 1];
            app.ContrastSlider_2.ValueChangedFcn = createCallbackFcn(app, @ContrastSlider_2ValueChanged, true);
            app.ContrastSlider_2.Value = [0 1];
            app.ChannelSpinner_2 = uispinner(app.ANALYSISTab);
            app.ChannelSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ChannelSpinner_2ValueChanged, true);
            app.ChannelSpinner_2Label = uilabel(app.ANALYSISTab);
            app.ChannelSpinner_2Label.Text = 'Channel';
            app.zsliceSpinner_2 = uispinner(app.ANALYSISTab);
            app.zsliceSpinner_2.ValueChangedFcn = createCallbackFcn(app, @zsliceSpinner_2ValueChanged, true);
            app.zsliceSpinner_2Label = uilabel(app.ANALYSISTab);
            app.zsliceSpinner_2Label.Text = 'z-slice';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SANIdentifierstatic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end